classdef InterfDataAnalysis_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainGUI                 matlab.ui.Figure
        MainAxes                matlab.ui.control.UIAxes
        BrowseRootDirButton     matlab.ui.control.Button
        DatafoldersList         matlab.ui.control.ListBox
        CurrDir                 matlab.ui.control.Label
        uipanel1                matlab.ui.container.Panel
        plot_Ncontours          matlab.ui.control.EditField
        text9                   matlab.ui.control.Label
        editMaxZ                matlab.ui.control.EditField
        text10                  matlab.ui.control.Label
        maxWL_text              matlab.ui.control.Label
        timescalemenu           matlab.ui.control.DropDown
        text12                  matlab.ui.control.Label
        minWL_text              matlab.ui.control.Label
        SNRtext                 matlab.ui.control.Label
        omega1_resolution_text  matlab.ui.control.Label
        Scans_text              matlab.ui.control.Label
        Nscans_number           matlab.ui.control.Label
        plot_Nwhites            matlab.ui.control.EditField
        text29                  matlab.ui.control.Label
        ShowContoursTick        matlab.ui.control.CheckBox
        plot_contourLineStyle   matlab.ui.control.EditField
        plot_pumpdirection      matlab.ui.control.DropDown
        text36                  matlab.ui.control.Label
        plot_axislegend         matlab.ui.control.DropDown
        text37                  matlab.ui.control.Label
        plot_colorrange         matlab.ui.control.EditField
        text38                  matlab.ui.control.Label
        plot_skiplevels         matlab.ui.control.EditField
        text39                  matlab.ui.control.Label
        text40                  matlab.ui.control.Label
        plot_contourfill        matlab.ui.control.CheckBox
        plot_colourscheme       matlab.ui.control.DropDown
        text42                  matlab.ui.control.Label
        text44                  matlab.ui.control.Label
        omega3_resolution_text  matlab.ui.control.Label
        SymColRange_tick        matlab.ui.control.CheckBox
        text46                  matlab.ui.control.Label
        binzero_text            matlab.ui.control.Label
        CutPlot_tick            matlab.ui.control.CheckBox
        text49                  matlab.ui.control.Label
        phase_text              matlab.ui.control.Label
        text51                  matlab.ui.control.Label
        FTsize_text             matlab.ui.control.Label
        EditProbeAxis_tick      matlab.ui.control.CheckBox
        editWLmin               matlab.ui.control.EditField
        text53                  matlab.ui.control.Label
        editWLmax               matlab.ui.control.EditField
        ShowSpecDiff            matlab.ui.control.CheckBox
        PlotPanel               matlab.ui.container.Panel
        BkgSubTick              matlab.ui.control.CheckBox
        make2Dplot              matlab.ui.control.Button
        ApplyChanges            matlab.ui.control.Button
        make3Dplot              matlab.ui.control.Button
        plot_Kinetics           matlab.ui.control.Button
        plot_Slices             matlab.ui.control.Button
        InteractiveModeTick     matlab.ui.control.CheckBox
        Normalise               matlab.ui.control.CheckBox
        Probe_Calibration       matlab.ui.control.CheckBox
        PumpCorrection_tick     matlab.ui.control.CheckBox
        SVDPanel                matlab.ui.container.Panel
        apodise_method          matlab.ui.control.DropDown
        text31                  matlab.ui.control.Label
        zeropad_tick            matlab.ui.control.CheckBox
        zeropad_next2k          matlab.ui.control.CheckBox
        zeropad_factor          matlab.ui.control.EditField
        text32                  matlab.ui.control.Label
        text34                  matlab.ui.control.Label
        phase_Npoints           matlab.ui.control.EditField
        phase_method            matlab.ui.control.DropDown
        text43                  matlab.ui.control.Label
        phase_coeffs            matlab.ui.control.EditField
        ExitButton              matlab.ui.control.Button
        AnalysisPanel           matlab.ui.container.Panel
        PowerDependence         matlab.ui.control.Button
        SubtractSpectra         matlab.ui.control.Button
        Fit                     matlab.ui.control.Button
        ShiftT2                 matlab.ui.control.Button
        SpectralDiffusion       matlab.ui.control.Button
        IntegralDynamics        matlab.ui.control.Button
        Refresh                 matlab.ui.control.Button
        UpDirectory             matlab.ui.control.Button
        DataTypeMenu            matlab.ui.control.DropDown
        Population_delay        matlab.ui.control.ListBox
        text41                  matlab.ui.control.Label
        SaveProbeCal            matlab.ui.control.Button
        VersionText             matlab.ui.control.Label
        GoTodayDir              matlab.ui.control.Button
        Transient2D_mode        matlab.ui.control.DropDown
        Transient2D_text        matlab.ui.control.Label
        text58                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function phase_factor_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function InterfDataAnalysis_GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   unrecognized PropertyName/PropertyValue pairs from the
%             %            command line (see VARARGIN)
%             
%             % Choose default command line output for InterfDataAnalysis_GUI
%             app.output = hObject;
%             
            % Set default directory:
            if exist('C:\GUIoptions.txt','file')==2
                app.defaultdir = readParam('default2DIRdir','C:\GUIoptions.txt');
            else
                app.defaultdir = [];
            end
%             
%             % Update version text string
%             app.VersionText.String = "v4.5a - 17.12.2018";
%             
%             % Disable annoying warnings
%             warning('off','MATLAB:Axes:NegativeLimitsInLogAxis');
%             warning('off','MATLAB:legend:IgnoringExtraEntries');
%             
%             % UIWAIT makes InterfDataAnalysis_GUI wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: ApplyChanges
        function ApplyChanges_Callback(app, event)
            if app.DataTypeMenu.Value ~= 4
                % Reprocess the data without reloading
                handles = process2DIR(handles,1);
                guidata(hObject,handles);
                % Clear error string
                set(app.ErrorText,'String', "");
                % Update the edit controls
                handles = UpdateEdits_2DIR(handles,1);
                % Plot the data in the main window (for preview)
                plot_2DIR(handles,app.MainAxes);
                set(app.MainAxes,'Visible','On')
                % Generate the secondary plot (show phasing data by default)
                SecondaryPlot(handles,app.SecondaryAxes,'ph');
                set(app.SecondaryAxes,'Visible','On')
                    app.ShowTimeDomain.Value = 0;
                    app.ShowProbeCalibration.Value = 0;
                    app.ShowPhasing.Value = 1;
            else
                app.WaitBar         = waitbar(0,'Loading data...');
                    for j=0:app.Ndelays
                        handles             = load2DIRlab3(handles,j);
                        guidata(hObject,handles)
                    end
                    % Update the edit controls
                    handles = UpdateEdits_2DIR(handles,1);
                    % Plot the data in the main window (for preview)
                    plot_2DIR(handles,app.MainAxes);
                    set(app.MainAxes,'Visible','On')
            
                    set(app.SecondaryAxes,'Visible','Off')
                    % Enable disabled controls
                    EnableControls_2DIR(handles)
                    % Update handles
                    guidata(hObject,handles)
            end
            % Delete progress bar
            delete(app.WaitBar);
        end

        % Button pushed function: BrowseRootDirButton
        function BrowseRootDirButton_Callback(app, event)
            rootdir = uigetdir(app.defaultdir);
            app.rootdir = rootdir;
            if rootdir ~= 0 % the user selected a directory
                % Update text label
                set(app.CurrDir, 'String', rootdir);
                if app.DataTypeMenu.Value ~= 4
                    % Put list of subfolders into subFolders
                        % Get a list of all files and folders in this folder.
                        files = dir(rootdir);
                        % Extract only those that are directories.
                        subFolders = files([files.isdir]);
                        % Don't show the temp folders in the list
                        subFolders = subFolders(~contains({subFolders.name},"temp",'IgnoreCase',1));
                    % Go to the selected folder
                        cd (rootdir);
                    % Sort the names and create variables for the list
                        dir_struct = subFolders;
                        [sorted_names,sorted_index] = sortrows({dir_struct.name}');
                        app.file_names = sorted_names;
                        app.is_dir = [dir_struct.isdir];
                        app.sorted_index = sorted_index;
                        guidata(hObject,handles)
                    % Update DatafoldersList, removing ".." and "." from the visible list
                        app.file_names = app.file_names(3:end);
                        set(app.DatafoldersList,'String',app.file_names,'Value',1)
                        guidata(hObject,handles)
                else
                    filelist            = dir(rootdir);
                    filenames           = {filelist.name}';
                    filenames           = filenames(~[filelist.isdir]);
                    filenames_delays    = filenames(~contains(filenames,'Pinhole','IgnoreCase',true));
                    filenames_delays    = filenames_delays(~contains(filenames_delays,'Phases','IgnoreCase',true));
            
                    % Read the formatted strings of each filename
                    Ndelays         = length(filenames_delays);
                    time            = zeros(1,Ndelays);
                    for i=1:Ndelays
                        parts       = strsplit(filenames_delays{i},'_');
                        time_str    = strsplit(parts{end-3},'fs');
                        time(i)     = str2double(time_str{1})./1000;
                    end
                    SampleName      = [parts{2:end-4}];
            
                    % WRITE TO HANDLES
                    app.t2delays    = time';
                    app.Ndelays     = Ndelays;
                    app.filenames   = filenames_delays;
                    % Write to the front panel
                    app.Population_delay.String = num2str(sort(app.t2delays));
                    set(app.DatafoldersList,'String',SampleName,'Value',1)
                    app.WaitBar         = waitbar(0,'Loading data...');
                    for j=0:Ndelays
                        handles             = load2DIRlab3(handles,j);
                        guidata(hObject,handles)
                    end
                    % Delete progress bar
                    delete(app.WaitBar);
                    % Update the edit controls
                    handles = UpdateEdits_2DIR(handles,1);
                    % Plot the data in the main window (for preview)
                    plot_2DIR(handles,app.MainAxes);
                    set(app.MainAxes,'Visible','On')
                    % Generate the secondary plot (show phasing data by default)
            %         SecondaryPlot(handles,app.SecondaryAxes,'td');
                    set(app.SecondaryAxes,'Visible','Off')
                    % Enable disabled controls
                    EnableControls_2DIR(handles)
                    % Update handles
                    guidata(hObject,handles)
                end
            else
                app.CurrDir.String = 'Please select a directory!  :(';
            end
        end

        % Value changed function: CutPlot_tick
        function CutPlot_tick_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: DataTypeMenu
        function DataTypeMenu_Callback(app, event)
            if app.DataTypeMenu.Value == 3
                app.Transient2D_text.Visible = 'On';
                app.Transient2D_mode.Visible = 'On';
            else
                app.Transient2D_text.Visible = 'Off';
                app.Transient2D_mode.Visible = 'Off';
            end
        end

        % Value changed function: DatafoldersList
        function DatafoldersList_Callback(app, event)
            if app.DataTypeMenu.Value ~= 4 % Only do something if not Lab 3
            
            % Determine whether the user double-clicked the folder or not
            switch get(gcf,'SelectionType')
            case 'normal' % Single click
                index_selected  = app.DatafoldersList.Value;
                file_list       = app.DatafoldersList.String;
                datafilename    = cellstr(file_list{index_selected}); % Item selected in list box
                % Write datafilename to handles(datafilename) and update
                app.datafilename = string(datafilename);
                guidata(hObject,handles)
                % If directory
                if  app.is_dir(app.sorted_index(index_selected))
                    % Load the data (replace handles) and update the main handles
                    error=0;
                    try
                       % Check the number of t2 delays
                       OldNt2delays = length(app.Population_delay.String);
                       %%% Load the data
                       switch app.DataTypeMenu.Value
                           case 1 % 2D-IR Lab 1 & Lab 4 (new)
                               handles = load2DIRlab1(handles);
                           case 2 % 2D-IR Lab 4 (old)
                               % Not implemented
                           case 3 % Transient 2D-IR
                               handles      = load2DIRlab1(handles);
                       end
                       guidata(hObject,handles)
                       %%% Process the data
                       if app.DataTypeMenu.Value ~= 4
                           handles = process2DIR(handles,0);
                       end
                       % Set t2 delay control values
                       app.Population_delay.String = num2str(app.t2delays);
                       % Ensure that datasets with less t2 delays are loaded correctly
                       NewNt2delays = size(app.t2delays,1);
                       if NewNt2delays < OldNt2delays
                           app.Population_delay.Value = 1;
                       end
                       app.t2delays = app.t2delays(:,1);
                       % Update handles
                       guidata(hObject,handles)
                       % Delete progress bar
                       delete(app.WaitBar);
                    catch err
                       set(app.ErrorText,'String', string(err.message));
                       error=1;
                    end
                    if error == 0
                        % Update the edit controls
                        handles = UpdateEdits_2DIR(handles,1);
                        % Plot the data in the main window (for preview)
                        plot_2DIR(handles,app.MainAxes);
                        set(app.MainAxes,'Visible','On')
                        % Generate the secondary plot (show phasing data by default)
                        SecondaryPlot(handles,app.SecondaryAxes,'ph');
                        set(app.SecondaryAxes,'Visible','On')
                            app.ShowTimeDomain.Value = 0;
                            app.ShowProbeCalibration.Value = 0;
                            app.ShowPhasing.Value = 1;
                        % Enable disabled controls
                        EnableControls_2DIR(handles)
                        % Update handles
                        guidata(hObject,handles)
                    end
                end
            case 'open'
                % Go to the selected folder
                index_selected = get(app.DatafoldersList,'Value');
                file_list = get(app.DatafoldersList,'String');
                datafilename = cellstr(file_list{index_selected}); % Item selected in list box
                % Write datafilename to handles(datafilename) and update
                filenamech = char(datafilename);
                % Go to the selected dir
                cd (filenamech);
                % Update the string
                app.CurrDir.String = pwd;
                app.rootdir = app.CurrDir.String;
                % Put list of subfolders into subFolders
                    % Get a list of all files and folders in this folder.
                    files = dir(app.rootdir);
                    % Extract only those that are directories.
                    subFolders = files([files.isdir]);
                    % Don't show the temp folders in the list
                    subFolders = subFolders(~contains({subFolders.name},"temp",'IgnoreCase',1));
                % Go to the selected folder
                    cd (app.rootdir);
                % Sort the names and create variables for the list
                    dir_struct = subFolders;
                    [sorted_names,sorted_index] = sortrows({dir_struct.name}');
                    app.file_names = sorted_names;
                    app.is_dir = [dir_struct.isdir];
                    app.sorted_index = sorted_index;
                    guidata(hObject,handles)
                % Update DatafoldersList, removing ".." and "." from the visible list
                    app.file_names = app.file_names(3:end);
                    set(app.DatafoldersList,'String',app.file_names,'Value',1)
                guidata(hObject,handles)
            end
            end
        end

        % Button pushed function: ExitButton
        function ExitButton_Callback(app, event)
            close all
        end

        % Button pushed function: Fit
        function Fit_Callback(app, event)
            handles = Gaussian2D_analysis(handles);
            guidata(hObject, handles);
        end

        % Button pushed function: GoTodayDir
        function GoTodayDir_Callback(app, event)
            % Go to the folder
            datedir     = datestr(now,'yyyymmdd');
            todaydir    = [app.defaultdir filesep datedir];
            if exist(todaydir,'dir') ~= 0
                cd (todaydir);
                % Update the string
                app.CurrDir.String = pwd;
                app.rootdir = app.CurrDir.String;
                % Put list of subfolders into subFolders
                    % Get a list of all files and folders in this folder.
                    files = dir(app.rootdir);
                    % Extract only those that are directories.
                    subFolders = files([files.isdir]);
                % Go to the selected folder
                    cd (app.rootdir);
                % Sort the names and create variables for the list
                    dir_struct = subFolders;
                    [sorted_names,sorted_index] = sortrows({dir_struct.name}');
                    app.file_names = sorted_names;
                    app.is_dir = [dir_struct.isdir];
                    app.sorted_index = sorted_index;
                    guidata(hObject,handles)
                % Update DatafoldersList, removing ".." and "." from the visible list
                    app.file_names = app.file_names(3:end);
                    set(app.DatafoldersList,'String',app.file_names,'Value',1)
                guidata(hObject,handles)
            end
        end

        % Button pushed function: IntegralDynamics
        function IntegralDynamics_Callback(app, event)
            % Ask the user what to plot
            integral_options    = {'Peak volume kinetics','Peak max-min kinetics','Peak volume difference kinetics','Noise analysis'};
            [integral_typeindx,doplot] = listdlg('ListString',integral_options,'OKstring','Plot','SelectionMode','single','ListSize',[200,100],'PromptString','Select kinetic analysis type:');
            
            if doplot == 0
                return
            end
            
            % Get some information from the GUI
            plot_pumpdirection  = char(app.plot_pumpdirection.String{app.plot_pumpdirection.Value});
            ProbeAxis           = app.ProbeAxis;
            PumpAxis            = app.PumpAxis;
            Ndelays             = app.Ndelays;
            t2delays            = app.t2delays;
            PROC_2D_DATA        = app.PROC_2D_DATA;
            Axes                = app.MainAxes;
            
            switch integral_options{integral_typeindx}
                case 'Peak volume kinetics'
                    % Get the desired values to plot from user
                    app.SelTraces = [];
            
                    switch app.InteractiveModeTick.Value
                        case 1
                            % Select points interactively, to finish hit RETURN
                            [RegionPositions,L] = SelectRegions(Axes);
                            % Separate the values into pump and probe, according to how the data is plotted
                            % i.e. convert from [x_min x_max y_min y_max] to [pump_min pump_max probe_min probe_max]
                            switch plot_pumpdirection
                                case 'Horizontal'
                                    pump_ranges     = RegionPositions(:,1:2);
                                    probe_ranges    = RegionPositions(:,3:4);
                                case 'Vertical'
                                    pump_ranges     = RegionPositions(:,3:4);
                                    probe_ranges    = RegionPositions(:,1:2);
                            end
                        case 0
                            RegionPositions_cell    = inputdlg({'Enter the pump regions (Format: min1 max1; min2 max2; ...): ','Enter the probe regions (Format: min1 max1; min2 max2; ...):'},'Define regions to integrate', [1 80]);
                            pump_ranges              = str2num(RegionPositions_cell{1});
                            probe_ranges             = str2num(RegionPositions_cell{2});
                            L = size(pump_ranges,1);
                            M = size(probe_ranges,1);
                            if L ~= M
                                warndlg('Number of pump and probe ranges is different!')
                                return
                            end
                    end
            
                    % Initialise variables
                    pump_idx    = zeros(L,2);
                    probe_idx   = zeros(L,2);
                    kindata     = zeros(Ndelays,L);
            
                    % Get the data to be plotted
                    for m=1:Ndelays
                        % Get the values from the 2D arrays for each population delay on the selected regions
                        % The data is by default in the format (w1,w3) (according to process2DIR.m)
                        for p=1:L
                            % Get the indices of the probe wavelengths
                            pump_idx(p,:)   = findClosestId2Val(PumpAxis{m,1},pump_ranges(p,:));
                            probe_idx(p,:)  = findClosestId2Val(ProbeAxis,probe_ranges(p,:));
                            tempdata        = PROC_2D_DATA{m,1}(pump_idx(p,1):pump_idx(p,2),probe_idx(p,1):probe_idx(p,2));
                            kindata(m,p)    = sum(tempdata(:));
                        end
                    end
                    % Normalise the data
                    if app.Normalise.Value == 1
                        kindata    = kindata./max(abs(kindata),[],1);
                        label      = 'Normalised Peak volume (a.u.)';
                    else
                        label      = 'Peak volume (a.u.)';
                    end
            
                    % Create a new figure
                    fh          = figure();
                    fh.Color    = [1 1 1];
            
                    % Define the axes
                    axes2       = axes('Parent',fh);
            
                    % Plot the data
                    cmap=colormap(othercolor('Mrainbow',L));
                    for n=1:L
                       plotname = ['(' num2str(PumpAxis{m,1}(pump_idx(n,1)),'%.4g') '-' num2str(PumpAxis{m,1}(pump_idx(n,2)),'%.4g') '; ' num2str(ProbeAxis(probe_idx(n,1)),'%.4g') '-' num2str(ProbeAxis(probe_idx(n,2)),'%.4g') ') cm^{-1}'];
                       plot(axes2,t2delays,kindata(:,n),'LineWidth',2,'Marker','o','MarkerSize',2,'color',cmap(n,:),'DisplayName',plotname);
                       hold on
                    end
            
                    %%% Nice formatting
                    set(gca,'FontSize',14)
                    xlabel('t_{2} delay (ps)','FontSize',14,'FontWeight','bold');
                    ylabel(label,'FontSize',14,'FontWeight','bold')
                    title([app.datafilename;'WAITING TIME KINETICS';''],'Interpreter','none','FontSize',10)
                    axis tight
            
                    % Show only positive t2 times
                    xlim([0.25 max(t2delays)]);
            
                    % Create legend
                    legend(gca,{},'FontSize',12)
                    legend('boxoff')
                    legend('Location','northeast')
            
                    % Set linear or log X scale
                    set(axes2,'xscale','lin');
            
                    % Add zero line
                    hline = refline(axes2,[0 0]); hline.Color = [0.5 0.5 0.5];
                    set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
            
                    % % Decide whether to save the plotted traces or not
                    % if app.DoSaveTraces==1
                    %     wavenumbers=transpose(app.cmprobe(k));
                    %     filename=char(strcat(app.CurrDir.String,filesep,app.datafilename,'_traces.dat'));
                    %     dlmwrite(filename,[[0;app.delays] [wavenumbers;kindata]])
                    % end
                    guidata(hObject,handles)
                case 'Noise analysis'
                    % Get the desired values to plot from user
                    app.SelTraces = [];
            
                    switch app.InteractiveModeTick.Value
                        case 1
                            % Select points interactively, to finish hit RETURN
                            [RegionPositions,L] = SelectRegions(Axes);
                            % Separate the values into pump and probe, according to how the data is plotted
                            % i.e. convert from [x_min x_max y_min y_max] to [pump_min pump_max probe_min probe_max]
                            switch plot_pumpdirection
                                case 'Horizontal'
                                    pump_ranges     = RegionPositions(:,1:2);
                                    probe_ranges    = RegionPositions(:,3:4);
                                case 'Vertical'
                                    pump_ranges     = RegionPositions(:,3:4);
                                    probe_ranges    = RegionPositions(:,1:2);
                            end
                        case 0
                            RegionPositions_cell    = inputdlg({'Enter the pump regions (Format: min1 max1; min2 max2; ...): ','Enter the probe regions (Format: min1 max1; min2 max2; ...):'},'Define regions to integrate', [1 80]);
                            pump_ranges              = str2num(RegionPositions_cell{1});
                            probe_ranges             = str2num(RegionPositions_cell{2});
                            L = size(pump_ranges,1);
                            M = size(probe_ranges,1);
                            if L ~= M
                                warndlg('Number of pump and probe ranges is different!')
                                return
                            end
                    end
            
                    % Initialise variables
                    pump_idx    = zeros(L,2);
                    probe_idx   = zeros(L,2);
                    noise       = zeros(Ndelays,L);
            
                    % Get the data to be plotted
                    for m=1:Ndelays
                        % Get the values from the 2D arrays for each population delay on the selected regions
                        % The data is by default in the format (w1,w3) (according to process2DIR.m)
                        for p=1:L
                            % Get the indices of the probe wavelengths
                            pump_idx(p,:)   = findClosestId2Val(PumpAxis{m,1},pump_ranges(p,:));
                            probe_idx(p,:)  = findClosestId2Val(ProbeAxis,probe_ranges(p,:));
                            tempdata        = PROC_2D_DATA{m,1}(pump_idx(p,1):pump_idx(p,2),probe_idx(p,1):probe_idx(p,2));
                            noise(m,p)      = std(tempdata(:));
                        end
                    end
                    warndlg(['The noise level is: ' num2str(noise)]);
                    guidata(hObject,handles)
            end
        end

        % Callback function
        function PixelNumber_Callback(app, event)
            if app.ShowTimeDomain.Value == 1
                SecondaryPlot(handles,app.SecondaryAxes,'td');
            end
        end

        % Value changed function: Population_delay
        function Population_delay_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
            if app.DataTypeMenu.Value ~= 4
                td = app.ShowTimeDomain.Value;
                ph = app.ShowPhasing.Value;
                pc = app.ShowProbeCalibration.Value;
                if td == 1
                    what = 'td';
                elseif ph == 1
                    what = 'ph';
                elseif pc == 1
                    what = 'pc';
                end
                SecondaryPlot(handles,app.SecondaryAxes,what);
            end
        end

        % Button pushed function: Refresh
        function Refresh_Callback(app, event)
            app.rootdir = app.CurrDir.String;
            % Put list of subfolders into subFolders
                % Get a list of all files and folders in this folder.
                files = dir(app.rootdir);
                % Extract only those that are directories.
                subFolders = files([files.isdir]);
                % Don't show the temp folders in the list
                subFolders = subFolders(~contains({subFolders.name},"temp",'IgnoreCase',1));
            % Go to the folder
                cd (app.rootdir);
            % Sort the names and create variables for the list
                dir_struct = subFolders;
                [sorted_names,sorted_index] = sortrows({dir_struct.name}');
                app.file_names = sorted_names;
                app.is_dir = [dir_struct.isdir];
                app.sorted_index = sorted_index;
                guidata(hObject,handles)
            % Update DatafoldersList, removing ".." and "." from the visible list
                app.file_names = app.file_names(3:end);
                set(app.DatafoldersList,'String',app.file_names,'Value',1)
                guidata(hObject,handles)
        end

        % Button pushed function: SaveProbeCal
        function SaveProbeCal_Callback(app, event)
            ProbeAxis = app.ProbeAxis;
            rootdir = app.rootdir;
            probecalfile= 'CalibratedProbe.csv';
            if exist(probecalfile,'file') == 0
                csvwrite([rootdir filesep probecalfile],ProbeAxis);
            else
                % Ask the user what to do
                choice = questdlg('A calibrated probe file already exists, overwrite?', ...
                    'Probe calibration', ...
                    'Yes','No','No');
                % Handle response
                switch choice
                    case 'Yes'
                        csvwrite([rootdir filesep probecalfile],ProbeAxis);
                    case 'No'
                        % Do nothing
                end
            end
        end

        % Value changed function: ShowContoursTick
        function ShowContoursTick_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: ShowSpecDiff
        function ShowSpecDiff_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Button pushed function: SpectralDiffusion
        function SpectralDiffusion_Callback(app, event)
            handles = SpectralDiffusion_analysis(handles);
            plot_2DIR(handles,app.MainAxes);
            guidata(hObject, handles);
        end

        % Button pushed function: SubtractSpectra
        function SubtractSpectra_Callback(app, event)
            % Ask the user what to plot
                sel_spectra     = inputdlg({'Enter the index of dataset A:','Enter the index of dataset B:'},'Subtract spectra (A-B)',[1 50],{'1','2'});
                if isempty(sel_spectra)
                    return
                end
            
            % Get the indices of the selected spectra
                A_idx = str2double(sel_spectra{1});
                B_idx = str2double(sel_spectra{2});
            
            % Process the spectra
                % Create a copy of the current handles structure to load the second dataset
                handles_B   = handles;
                file_list   = app.DatafoldersList.String;
            
                %%% Process A
                    % Define filename
                    app.datafilename  = string(cellstr(file_list{A_idx}));
                    % Load data
                    handles               = load2DIRlab1(handles);
                    % Phase spectra
                    handles               = process2DIR(handles,0);
                    % Delete progress bar
                    delete(app.WaitBar);
            
                %%% Process B
                    % Define filename
                    handles_B.datafilename  = string(cellstr(file_list{B_idx}));
                    % Load data
                    handles_B               = load2DIRlab1(handles_B);
                    % Phase spectra
                    handles_B               = process2DIR(handles_B,0);
                    % Delete progress bar
                    delete(handles_B.WaitBar);
            
            % Calculate the differential 2D-IR spectra
                % Get the phased 2D data
                A_data  = app.PROC_2D_DATA;
                B_data  = handles_B.PROC_2D_DATA;
            
                % Check if the datasets have the number of t2 delays
                if length(A_data) ~= length(B_data)
                    opts.Interpreter    = 'tex';
                    opts.WindowStyle    = 'replace';
                    warndlg('Datasets do not have the same number of \itt_2\rm delays!','Warning',opts);
                    return
                end
            
                % Check if the datasets have the same FT and pixel size
                if length(A_data{1,1}) ~= length(B_data{1,1})
                    opts.Interpreter    = 'tex';
                    opts.WindowStyle    = 'replace';
                    warndlg('Datasets do not have the same \omega_1 size!','Warning',opts);
                    return
                end
            
                % Preallocate variables
                Ncols       = size(A_data,2);
                diff_data   = cell(length(A_data),Ncols);
            
                % If everything is OK, do the subtraction
                for i=1:length(A_data)
                    diff_data{i}    = A_data{i} - B_data{i};
                end
            
            % Write back to main handles structure
                app.PROC_2D_DATA = diff_data;
            
            % Update controls, plot the data and save to the main handles structure
                % Update the edit controls
                handles = UpdateEdits_2DIR(handles,1);
                % Plot the data in the main window (for preview)
                plot_2DIR(handles,app.MainAxes);
                set(app.MainAxes,'Visible','On')
                % Generate the secondary plot (show phasing data by default)
                SecondaryPlot(handles,app.SecondaryAxes,'ph');
                set(app.SecondaryAxes,'Visible','On')
                    app.ShowTimeDomain.Value = 0;
                    app.ShowProbeCalibration.Value = 0;
                    app.ShowPhasing.Value = 1;
                % Enable disabled controls
                EnableControls_2DIR(handles)
                % Update handles
                guidata(hObject,handles)
        end

        % Value changed function: SymColRange_tick
        function SymColRange_tick_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: Transient2D_mode
        function Transient2D_mode_Callback(app, event)
            % Reprocess the data without reloading
            handles = process2DIR(handles,1);
            guidata(hObject,handles);
            % Clear error string
            set(app.ErrorText,'String', "");
            % Update the edit controls
            handles = UpdateEdits_2DIR(handles,1);
            % Plot the data in the main window (for preview)
            plot_2DIR(handles,app.MainAxes);
            set(app.MainAxes,'Visible','On')
            % Generate the secondary plot (show phasing data by default)
            SecondaryPlot(handles,app.SecondaryAxes,'ph');
            set(app.SecondaryAxes,'Visible','On')
                app.ShowTimeDomain.Value = 0;
                app.ShowProbeCalibration.Value = 0;
                app.ShowPhasing.Value = 1;
            % Delete progress bar
            delete(app.WaitBar);
        end

        % Button pushed function: UpDirectory
        function UpDirectory_Callback(app, event)
            % Go to the folder
            cd (app.CurrDir.String);
            % Go one folder Up
            cd ..;
            % Update the string
            app.CurrDir.String = pwd;
            app.rootdir = app.CurrDir.String;
            % Put list of subfolders into subFolders
                % Get a list of all files and folders in this folder.
                files = dir(app.rootdir);
                % Extract only those that are directories.
                subFolders = files([files.isdir]);
                % Don't show the temp folders in the list
                subFolders = subFolders(~contains({subFolders.name},"temp",'IgnoreCase',1));
            % Go to the selected folder
                cd (app.rootdir);
            % Sort the names and create variables for the list
                dir_struct = subFolders;
                [sorted_names,sorted_index] = sortrows({dir_struct.name}');
                app.file_names = sorted_names;
                app.is_dir = [dir_struct.isdir];
                app.sorted_index = sorted_index;
                guidata(hObject,handles)
            % Update DatafoldersList, removing ".." and "." from the visible list
                app.file_names = app.file_names(3:end);
                set(app.DatafoldersList,'String',app.file_names,'Value',1)
            guidata(hObject,handles)
        end

        % Value changed function: editWLmax
        function editWLmax_Callback(app, event)
            maxWL       = str2double(app.editWLmax.String);
            minWL       = str2double(app.editWLmin.String);
            plotaxis    = app.MainAxes;
            plot_pumpdirection  = char(app.plot_pumpdirection.String{app.plot_pumpdirection.Value});
            if app.EditProbeAxis_tick.Value == 1
                switch plot_pumpdirection
                case 'Vertical'
                    xlim(plotaxis,[minWL,maxWL]);
                case 'Horizontal'
                    ylim(plotaxis,[minWL,maxWL]);
                end
            else
                switch plot_pumpdirection
                case 'Vertical'
                    ylim(plotaxis,[minWL,maxWL]);
                case 'Horizontal'
                    xlim(plotaxis,[minWL,maxWL]);
                end
            end
        end

        % Value changed function: editWLmin
        function editWLmin_Callback(app, event)
            maxWL       = str2double(app.editWLmax.String);
            minWL       = str2double(app.editWLmin.String);
            plotaxis    = app.MainAxes;
            plot_pumpdirection  = char(app.plot_pumpdirection.String{app.plot_pumpdirection.Value});
            if app.EditProbeAxis_tick.Value == 1
                switch plot_pumpdirection
                case 'Vertical'
                    xlim(plotaxis,[minWL,maxWL]);
                case 'Horizontal'
                    ylim(plotaxis,[minWL,maxWL]);
                end
            else
                switch plot_pumpdirection
                case 'Vertical'
                    ylim(plotaxis,[minWL,maxWL]);
                case 'Horizontal'
                    xlim(plotaxis,[minWL,maxWL]);
                end
            end
        end

        % Button pushed function: make2Dplot
        function make2Dplot_Callback(app, event)
            % Copy current plot limits
                xl= xlim(app.MainAxes);
                yl= ylim(app.MainAxes);
            % Create figure
                fh = figure;
                ax = axes(fh);
                ax.Visible='Off';
            plot_2DIR(handles,ax);
                fh.Color = [1 1 1];
            % Make uniform, consistent format
                ax.FontSize = 12;
                ax.LineWidth = 1;
                ax.TickLength = [0.015 0.035];
                ax.Visible='On';
            % Restore axes limits
                xlim(ax,xl)
                ylim(ax,yl)
        end

        % Button pushed function: make3Dplot
        function make3Dplot_Callback(app, event)
            fh = figure;
            ax = axes(fh);
            ax.Visible='Off';
            SurfPlot_2DIR(handles,ax);
            fh.Color = [1 1 1];
            % Make uniform, consistent format
            ax.FontSize = 12;
            ax.LineWidth = 1;
            ax.TickLength = [0.015 0.035];
            ax.Visible='On';
        end

        % Button pushed function: plot_Kinetics
        function plot_Kinetics_Callback(app, event)
            % Get some information from the GUI
            plot_pumpdirection  = char(app.plot_pumpdirection.String{app.plot_pumpdirection.Value});
            ProbeAxis           = app.ProbeAxis;
            PumpAxis            = app.PumpAxis;
            Ndelays             = app.Ndelays;
            t2delays            = app.t2delays;
            PROC_2D_DATA        = app.PROC_2D_DATA;
            
            % Get the desired values to plot from user
                app.SelTraces = [];
                switch app.InteractiveModeTick.Value
                    case 1
                        % Select points interactively, to finish hit RETURN
                        handles = SelectTraces(handles,0);
                        if isempty(app.SelTraces)
                            return
                        end
                        EnT = 0;
                    case 0
                        app.SelTraces = inputdlg('Enter the coordinates of the points to plot along t2 (format: x1,y1;x2,y2...):',...
                             'Input desired coordinates', [1 60]);
                        if isempty(app.SelTraces)
                            return
                        end
                        % Plot special cases
                        if strcmp(app.SelTraces,'EnT-GSB')
                            app.SelTraces = [1979,1979;1979,2028;2028,2028;2028,1979];
                            EnT = 1;
                        elseif strcmp(app.SelTraces,'EnT-ESA')
                            app.SelTraces = [1979,1967;1979,2017;2028,2017;2028,1967];
                            EnT = 1;
                        elseif strcmp(app.SelTraces,'Andrea')
                            app.SelTraces = [1985,1985;1985,2062;2062,2062;2062,1985];
                            EnT = 2;
                        else
                            app.SelTraces = str2num(app.SelTraces{:});
                            EnT = 0;
                        end
                end
                L = size(app.SelTraces,1);
            
            % Separate the values into pump and probe, according to how the data is plotted
            switch plot_pumpdirection
                case 'Horizontal'
                    pump_search     = app.SelTraces(:,1);
                    probe_search    = app.SelTraces(:,2);
                case 'Vertical'
                    pump_search     = app.SelTraces(:,2);
                    probe_search    = app.SelTraces(:,1);
            end
            
            % Find the desired values in the pump and probe axis
            for i=1:L
                probe_index(i)          = findClosestId2Val(ProbeAxis,probe_search(i));
                for m=1:Ndelays
                    pump_index(m,i)     = findClosestId2Val(PumpAxis{m,1},pump_search(i));
                end
            end
            
            % Initialise variables
            caption = cell(L,1);
            kindata = zeros(Ndelays,L);
            
            for i=1:L
                % Get the values from the 2D arrays for each population delay and plot them
                % The data is by default in the format (w1,w3) (according to process2DIR.m)
                % The vector ydata will have the following format: (assuming N traces and M t2 delays)
                %         t2 delay 1: [Z1 Z2 Z3 ... Zn]
                %         t2 delay 2: [Z1 Z2 Z3 ... Zn]
                %         t2 delay 3: [Z1 Z2 Z3 ... Zn]
                %             ...
                %         t2 delay m: [Z1 Z2 Z3 ... Zn]
            
                % Get the data and normalise (if needed)
                for m=1:Ndelays
                    kindata(m,i) = PROC_2D_DATA{m,1}(pump_index(m,i),probe_index(i));
                end
            
                switch app.Normalise.Value
                    case 0
                        label = '2D signal (a.u.)';
                        caption{i} = ['(' num2str(round(PumpAxis{1,1}(pump_index(1,i)))) ', ' num2str(ProbeAxis(probe_index(i))) ') cm^{-1}'];
                    case 1
                        t0_index = findClosestId2Val(t2delays,0);
                        maxval = max(kindata(t0_index:end,i));
                        minval = min(kindata(t0_index:end,i));
                        if abs(maxval) >= abs(minval)
                            kindata(:,i) = kindata(:,i)./maxval;
                            caption{i} = ['(' num2str(round(PumpAxis{1,1}(pump_index(1,i)))) ', ' num2str(ProbeAxis(probe_index(i))) ') cm^{-1}'];
                        else
                            kindata(:,i) = kindata(:,i)./minval;
                            caption{i} = ['(' num2str(round(PumpAxis{1,1}(pump_index(1,i)))) ', ' num2str(ProbeAxis(probe_index(i))) ') cm^{-1} \times -1'];
                        end
                        label = 'Normalised 2D signal (a.u.)';
                end
            end


            % Prepare plot for EnT
            if EnT ~= 0
                if abs(max(kindata(:))) <= abs(min(kindata(:)))
                    kindata = -kindata;
                end
            
                diagFW          = kindata(:,1)./max(max(abs(kindata(:,1:2))));
                diagBW          = kindata(:,3)./max(max(abs(kindata(:,3:4))));
                xpeakFW         = 10.*kindata(:,2)./max(max(abs(kindata(:,1:2))));
                xpeakBW         = 10.*kindata(:,4)./max(max(abs(kindata(:,3:4))));
                time            = app.t2delays;
                % Create figure
                fh              = figure;
                fh.Units        = 'normalized';
                fh.Position(2)  = 0.1;
                fh.Position(4)  = fh.Position(4)*2;
            
                fh.Color        = [1 1 1];
                fh.Units        = 'pixels';
                ax_FW           = subplot(2,1,1);
                ax_BW           = subplot(2,1,2);
                ax_FW.FontSize  = 16;
                ax_BW.FontSize  = 16;
            
                box(ax_FW,'on');
                box(ax_BW,'on');
            
                hold(ax_FW,'on');
                hold(ax_BW,'on');
                switch EnT
                    case 1
                        diag1 = 'Diagonal Re(^{13}CO)';
                        diag2 = 'Diagonal Re(^{12}CO)';
                        xpeak1= 'Re(^{13}CO) \rightarrow Re(^{12}CO) \rm{\times10}';
                        xpeak2= 'Re(^{13}CO) \leftarrow Re(^{12}CO) \rm{\times10}';
                        titFW = 'Forward energy transfer';
                        titBW = 'Backward energy transfer';
                    case 2
                        diag2 = 'Diagonal SCN^{-}';
                        diag1 = 'Diagonal S^{13}C^{15}N^{-}';
                        xpeak2= 'SCN^{-} \rightarrow S^{13}C^{15}N^{-} \rm{\times 10}';
                        xpeak1= 'SCN^{-} \leftarrow S^{13}C^{15}N^{-} \rm{\times 10}';
                        titBW = 'Downhill transfer';
                        titFW = 'Uphill transfer';
                end
                % Plot the diagonal peaks
                plot(ax_FW,time,diagFW,'-or','LineWidth',1,'DisplayName',diag1);
                plot(ax_BW,time,diagBW,'-ob','LineWidth',1,'DisplayName',diag2);
            
                % Plot the cross peaks
                plot(ax_FW,time,xpeakFW,'-^r','LineWidth',1,'DisplayName',xpeak1);
                plot(ax_BW,time,xpeakBW,'-vb','LineWidth',1,'DisplayName',xpeak2);
            
                % Set axes limits
                axis(ax_FW,'tight');
                axis(ax_BW,'tight');
            
                %%% Nice formatting
                % Titles
                title(ax_FW,titFW,'FontSize',16);
                title(ax_BW,titBW,'FontSize',16);
            
                % Axis labels
                xlabel(ax_FW,'t_2 delay (ps)','FontWeight','bold','FontSize',16);
                xlabel(ax_BW,'t_2 delay (ps)','FontWeight','bold','FontSize',16);
            
                ylabel(ax_FW,'Normalised 2D signal','FontWeight','bold','FontSize',16);
                ylabel(ax_BW,'Normalised 2D signal','FontWeight','bold','FontSize',16);
            
                % Axis limits
                xlim(ax_FW,[0 time(end)]);
                xlim(ax_BW,[0 time(end)]);
            
                ylim(ax_FW,[-0.1 1.1]);
                ylim(ax_BW,[-0.1 1.1]);
                % Add zero line
                hline_FW = refline(ax_FW,[0 0]); hline_FW.Color = [0.5 0.5 0.5];
                set(get(get(hline_FW,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
                hline_BW = refline(ax_BW,[0 0]); hline_BW.Color = [0.5 0.5 0.5];
                set(get(get(hline_BW,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
            
                % Legends
                lh_FW = legend(ax_FW,'show');
                legend(ax_FW,'boxoff','FontWeight','bold')
                legend(ax_FW,{},'FontWeight','bold')
            
                lh_BW = legend(ax_BW,'show');
                legend(ax_BW,'boxoff')
                legend(ax_BW,{},'FontWeight','bold')
                % Make figure resizable
                fh.Units        = 'normalized';
            else
                % Create a new figure with consistent format
                fh = figure();
                fh.Position(3)  = 800;
                fh.Position(4)  = 425;
                fh.Color        = [1 1 1];
                % Define the axes
                axes2 = axes('Parent',fh);
                axes(axes2);
                cmap=colormap(othercolor('Mrainbow',L));
                % Plot the data
                for n=1:L
                   plot(axes2,app.t2delays,kindata(:,n),'LineWidth',2,'Marker','o','MarkerSize',2,'color',cmap(n,:));
                   hold on
                end
                %%% Nice formatting
                set(gca,'FontSize',14)
                xlabel('t_{2} delay (ps)','FontSize',14,'FontWeight','bold');
                ylabel(label,'FontSize',14,'FontWeight','bold')
                % title([app.datafilename;'WAITING TIME KINETICS';''],'Interpreter','none','FontSize',10)
                axis tight
            
                % Show only positive t2 times
                xlim([0 max(t2delays)]);
            
                % Create legend
                legend(gca,caption,'FontSize',12)
                legend('boxoff')
                legend('Location','northeast')
            
                % Set linear or log X scale
                set(axes2,'xscale','lin');
            
                % Add zero line
                hline = refline(axes2,[0 0]); hline.Color = [0.5 0.5 0.5];
                set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
            
                axes2.Units     = 'pixels';
                axes2.Position  = [75 75 675 320];
                axes2.Units     = 'normalized';
            end
            
            
            % Decide whether to save the plotted traces or not
            % if app.DoSaveTraces==1
            %     wavenumbers=transpose(app.cmprobe(k));
                filename    = char(strcat(app.CurrDir.String,filesep,app.datafilename,'_traces.dat'));
            %     data        = [[[0;PumpAxis{1,1}(pump_index(1,:))],[0;ProbeAxis(probe_index)]]';[app.t2delays(2:end),kindata(2:end,:)]];
                data        = [[[0;PumpAxis{1,1}(pump_index(1,:))],[0;ProbeAxis(probe_index)]]';[app.t2delays,[diagFW xpeakFW diagBW xpeakBW]]];
                dlmwrite(filename,data);
            % end
            guidata(hObject,handles)
        end

        % Value changed function: plot_Ncontours
        function plot_Ncontours_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: plot_Nwhites
        function plot_Nwhites_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Button pushed function: plot_Slices
        function plot_Slices_Callback(app, event)
            % Ask the user what to plot
            slice_options = {'Diagonal','Along fixed pump WL','Along fixed probe WL','Integrate along pump axis','Integrate along probe axis','Along several pump WL','Along several probe WL'};
            [slice_typeindx,doplot] = listdlg('ListString',slice_options,'OKstring','Plot','SelectionMode','single','ListSize',[150,120],'PromptString','Select slice type to plot:');
            
            if doplot == 0
                return
            end
            
            % Get some information from the GUI
            plot_pumpdirection  = char(app.plot_pumpdirection.String{app.plot_pumpdirection.Value});
            ProbeAxis           = app.ProbeAxis;
            PumpAxis            = app.PumpAxis;
            Ndelays             = app.Ndelays;
            t2delays            = app.t2delays;
            PROC_2D_DATA        = app.PROC_2D_DATA;
            PopDelay            = app.Population_delay.Value;
            Normalise           = app.Normalise.Value;
            
            switch slice_options{slice_typeindx}
                case 'Diagonal'
                    % Initialise variables
                    pump_indexes    = cell(Ndelays,1);
                    data            = zeros(length(ProbeAxis),Ndelays);
            
                    % Get the data to be plotted
                    for m=1:Ndelays
                        % Get the indices of the probe wavelengths in the pump axis
                        pump_indexes{m}     = findClosestId2Val(PumpAxis{m,1},transpose(ProbeAxis));
                        probe_indexes       = 1:length(ProbeAxis);
                        % Get the data
                        data(:,m)           = diag(PROC_2D_DATA{m,1}(pump_indexes{m},probe_indexes));
                    end
            
                    % Create a new figure
                    fh = figure();
            
                    % Define the axes
                    app.axes2 = axes(fh);
            
                    % Plot the data
                    cmap=colormap(othercolor('Mrainbow',Ndelays));
                    for m=1:Ndelays
                       plot(app.axes2,ProbeAxis,data(:,m),'-','LineWidth',2,'MarkerSize',2,'color',cmap(m,:),'DisplayName',['t_{2} = ' num2str(t2delays(m),'%.3g') ' ps']);
                       hold on
                    end
            
                    % Save plot details
                    plot_title  = 'DIAGONAL CUTS';
                    x_axis      = 'Probe';
            
                    %%% Nice formatting
                    % Size and colour
                    fh.Color = [1 1 1];
                    currsize = fh.Position;
                    fh.Position = [currsize(1:2) 900 425];
                    app.axes2.Units = 'Pixels';
                    currsize = app.axes2.OuterPosition;
                    app.axes2.OuterPosition = [currsize(1:2) 950 420];
                    app.axes2.Units = 'Normalized';
            
                    % Title, axis labels and legend
                    app.axes2.FontSize = 14;
                    xlabel(app.axes2,[x_axis ' wavelength (cm^{-1})'],'FontSize',14,'FontWeight','bold');
                    ylabel(app.axes2,'2D signal (a.u.)','FontSize',14,'FontWeight','bold')
                    title(app.axes2,[app.datafilename;plot_title;''],'Interpreter','none','FontSize',10)
                    axis tight
            
                    % Create legend
                    legend(gca);
                    legend('boxoff')
                    legend('Location','eastoutside')
            
                    % Add zero line
                    hline = refline(app.axes2,[0 0]);
                    hline.Color = [0.5 0.5 0.5];
                    set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
            
                case 'Along fixed pump WL'
                    switch app.InteractiveModeTick.Value
                        case 1
                            % Select points interactively, to finish hit RETURN
                            handles = SelectTraces(handles,0);
                            % Separate the values into pump and probe, according to how the data is plotted
                            switch plot_pumpdirection
                                case 'Horizontal'
                                    pump_search    = app.SelTraces(:,1);
                                case 'Vertical'
                                    pump_search    = app.SelTraces(:,2);
                            end
                        case 0
                            app.SelTraces = inputdlg('Enter the pump wavenumbers to plot:',...
                                 'Input pump wavenumbers to plot:', [1 60]);
                            pump_search = str2num(app.SelTraces{:})';
                    end
                    L = length(pump_search);
            
                    % Initialise variables
                    pump_indexes    = cell(Ndelays,1);
                    data            = zeros(length(ProbeAxis),Ndelays,L);
            
                    % Get the data to be plotted
                    for m=1:Ndelays
                        % Get the indices of the probe wavelengths
                        pump_indexes{m}     = findClosestId2Val(PumpAxis{m,1},pump_search);
                        probe_indexes       = 1:length(ProbeAxis);
                        % Get the data
                        for p=1:L
                            data(:,m,p)     = transpose(PROC_2D_DATA{m,1}(pump_indexes{m}(p),probe_indexes));
                        end
                    end
            
                    % Plot each pump wavelength in one new figure
                    for p=1:L
                        % Create a new figure
                        fh = figure();
            
                        % Define the axes
                        app.axes2 = axes(fh);
            
                        % Plot the data
                        cmap=colormap(othercolor('Mrainbow',Ndelays));
                        for m=1:Ndelays
                           plot(app.axes2,ProbeAxis,data(:,m,p),'-','LineWidth',2,'MarkerSize',2,'color',cmap(m,:),'DisplayName',['t_{2} = ' num2str(t2delays(m),'%.3g') ' ps']);
                           hold on
                        end
            
                        % Save plot details
                        plot_title  = ['TRANSIENTS AT ' num2str(PumpAxis{1,1}(pump_indexes{1}(p)),'%.4g') ' cm^{-1} (pump)'];
                        x_axis      = 'Probe';
            
                        %%% Nice formatting
                        % Size and colour
                        fh.Color = [1 1 1];
                        currsize = fh.Position;
                        fh.Position = [currsize(1:2) 900 425];
                        app.axes2.Units = 'Pixels';
                        currsize = app.axes2.OuterPosition;
                        app.axes2.OuterPosition = [currsize(1:2) 950 420];
                        app.axes2.Units = 'Normalized';
            
                        % Title, axis labels and legend
                        app.axes2.FontSize = 14;
                        xlabel(app.axes2,[x_axis ' wavelength (cm^{-1})'],'FontSize',14,'FontWeight','bold');
                        ylabel(app.axes2,'2D signal (a.u.)','FontSize',14,'FontWeight','bold')
                        title(app.axes2,plot_title,'FontSize',10)
                        axis tight
            
                        % Create legend
                        legend(gca);
                        legend('boxoff')
                        legend('Location','eastoutside')
            
                        % Add zero line
                        hline = refline(app.axes2,[0 0]);
                        hline.Color = [0.5 0.5 0.5];
                        set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
                    end
            
                case 'Along fixed probe WL'
                    % Get the desired values to plot from user
                    app.SelTraces = [];
                    switch app.InteractiveModeTick.Value
                        case 1
                            % Select points interactively, to finish hit RETURN
                            handles = SelectTraces(handles,0);
                            % Separate the values into pump and probe, according to how the data is plotted
                            switch plot_pumpdirection
                                case 'Horizontal'
                                    probe_search    = app.SelTraces(:,2);
                                case 'Vertical'
                                    probe_search    = app.SelTraces(:,1);
                            end
                        case 0
                            app.SelTraces = inputdlg('Enter the probe wavenumbers to plot:',...
                                 'Input probe wavenumbers to plot:', [1 60]);
                            probe_search = str2num(app.SelTraces{:});
                    end
                    L = length(probe_search);
            
                    % Get the segment of the pump axis
                    for m=1:Ndelays
                        if app.CutPlot_tick.Value
                            min_pump        = findClosestId2Val(PumpAxis{m,1},min(ProbeAxis));
                            max_pump        = findClosestId2Val(PumpAxis{m,1},max(ProbeAxis));
                            pump_indexes{m} = min_pump:1:max_pump;
                        else
                            pump_indexes{m} = 1:1:length(PumpAxis{m,1});
                        end
                    end
            
                    % Initialise variables
                    data                    = zeros(length(PumpAxis{1,1}(pump_indexes{m})),Ndelays,L);
            
                    % Get the probe indexes
                    probe_indexes           = findClosestId2Val(ProbeAxis,probe_search);
            
                    % Get the data to be plotted
                    for m=1:Ndelays
                        for p=1:L
                            data(:,m,p)     = PROC_2D_DATA{m,1}(pump_indexes{m},probe_indexes(p));
                        end
                    end
            
                    % Plot each probe wavelength in one new figure
                    for p=1:L
                        % Create a new figure
                        fh = figure();
            
                        % Define the axes
                        app.axes2 = axes(fh);
            
                        % Plot the data
                        cmap=colormap(othercolor('Mrainbow',Ndelays));
                        for m=1:Ndelays
                           plot(app.axes2,PumpAxis{m,1}(pump_indexes{m}),data(:,m,p),'-','LineWidth',2,'MarkerSize',2,'color',cmap(m,:),'DisplayName',['t_{2} = ' num2str(t2delays(m),'%.3g') ' ps']);
                           hold on
                        end
            
                        % Save plot details
                        plot_title  = ['TRANSIENTS AT ' num2str(ProbeAxis(probe_indexes(p)),'%.4g') ' cm^{-1} (probe)'];
                        x_axis      = 'Pump';
            
                        %%% Nice formatting
                        % Size and colour
                        fh.Color = [1 1 1];
                        currsize = fh.Position;
                        fh.Position = [currsize(1:2) 900 425];
                        app.axes2.Units = 'Pixels';
                        currsize = app.axes2.OuterPosition;
                        app.axes2.OuterPosition = [currsize(1:2) 950 420];
                        app.axes2.Units = 'Normalized';
            
                        % Title, axis labels and legend
                        app.axes2.FontSize = 14;
                        xlabel(app.axes2,[x_axis ' wavelength (cm^{-1})'],'FontSize',12,'FontWeight','bold');
                        ylabel(app.axes2,'2D signal (a.u.)','FontSize',12,'FontWeight','bold')
                        title(app.axes2,plot_title,'FontSize',10)
                        axis tight
            
                        % Create legend
                        legend(gca);
                        legend('boxoff')
                        legend('Location','eastoutside')
            
                        % Add zero line
                        hline = refline(app.axes2,[0 0]);
                        hline.Color = [0.5 0.5 0.5];
                        set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
                    end
            
                case 'Integrate along pump axis'
                    switch app.InteractiveModeTick.Value
                        case 0
                            % Ask user for pump range (default is same as probe range)
                            defaults  = {num2str([ProbeAxis(1) ProbeAxis(end)])};
                            SelTraces = inputdlg('Enter the pump range to integrate:',...
                                         'Input pump wavenumber range to integrate:', [1 60],defaults);
                            SelTraces = str2num(SelTraces{:});
                        case 1
                            SelTraces = [ProbeAxis(1) ProbeAxis(end)];
                    end
            
                    % Get the indices of the pump wavelengths to integrate
                    pump_indexes    = findClosestId2Val(PumpAxis{1,1},SelTraces);
                    % Initialize variables
                    data            = zeros(length(ProbeAxis),Ndelays);
                    % Get the data to be plotted
                    for m=1:Ndelays
                        data(:,m)   = transpose(sum(PROC_2D_DATA{m,1}(min(pump_indexes):max(pump_indexes),:),1));
                    end
            
                    % Create a new figure
                    fh = figure();
            
                    % Define the axes
                    app.axes2 = axes(fh);
            
                    % Plot the data
                    cmap=colormap(othercolor('Mrainbow',Ndelays));
                    for m=1:Ndelays
                       plot(app.axes2,ProbeAxis,data(:,m),'-','LineWidth',2,'MarkerSize',2,'color',cmap(m,:),'DisplayName',[num2str(t2delays(m),'%.3g') ' ps']);
                       hold on
                    end
            
                    % Save plot details
                    plot_title  = ['INTEGRATED 2D SIGNAL FOR PUMP WL' num2str(PumpAxis{1,1}(min(pump_indexes))) ' to ' num2str(PumpAxis{1,1}(max(pump_indexes))) ' cm^{-1}'];
                    x_axis      = 'Probe';
            
                    %%% Nice formatting
                    % Size and colour
                    fh.Color = [1 1 1];
                    currsize = fh.Position;
                    fh.Position = [currsize(1:2) 900 425];
                    app.axes2.Units = 'Pixels';
                    currsize = app.axes2.OuterPosition;
                    app.axes2.OuterPosition = [currsize(1:2) 950 420];
                    app.axes2.Units = 'Normalized';
            
                    % Title, axis labels and legend
                    app.axes2.FontSize = 14;
                    xlabel(app.axes2,[x_axis ' wavelength (cm^{-1})'],'FontSize',12,'FontWeight','bold');
                    ylabel(app.axes2,'2D signal (a.u.)','FontSize',12,'FontWeight','bold')
                    title(app.axes2,plot_title,'FontSize',10)
                    axis tight
            
                    % Create legend
                    legend(gca);
                    legend('boxoff')
                    legend('Location','eastoutside')
            
                    % Add zero line
                    hline = refline(app.axes2,[0 0]);
                    hline.Color = [0.5 0.5 0.5];
                    set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
            
                case 'Integrate along probe axis'
                    switch app.InteractiveModeTick.Value
                        case 0
                            % Ask user for pump range (default is same as probe range)
                            defaults          = {num2str([ProbeAxis(1) ProbeAxis(end)])};
                            SelTraces = inputdlg('Enter the pump range to show:',...
                                         'Input pump wavenumber range to show:', [1 60],defaults);
                            SelTraces = str2num(SelTraces{:});
                        case 1
                            SelTraces = [ProbeAxis(1) ProbeAxis(end)];
                    end
            
                    % Get the indices of the pump wavelengths
                    pump_indexes    = findClosestId2Val(PumpAxis{1,1},SelTraces);
                    % Initialize variables
                    data            = zeros((max(pump_indexes)-min(pump_indexes)+1),Ndelays);
                    % Get the data to be plotted
                    for m=1:Ndelays
                        data(:,m)   = sum(PROC_2D_DATA{m,1}(min(pump_indexes):max(pump_indexes),:),2);
                    end
            
                    % Create a new figure
                    fh = figure();
            
                    % Define the axes
                    app.axes2 = axes(fh);
            
                    % Plot the data
                    cmap=colormap(othercolor('Mrainbow',Ndelays));
                    for m=1:Ndelays
                       plot(app.axes2,PumpAxis{1,1}(min(pump_indexes):max(pump_indexes)),data(:,m),'-','LineWidth',2,'MarkerSize',2,'color',cmap(m,:),'DisplayName',['t_{2} = ' num2str(t2delays(m),'%.3g') ' ps']);
                       hold on
                    end
            
                    % Save plot details
                    plot_title  = ['2D SIGNAL, INTEGRAL ACROSS ALL PROBE WAVELENGTHS'];
                    x_axis      = 'Pump';
            
                    %%% Nice formatting
                    % Size and colour
                    fh.Color = [1 1 1];
                    currsize = fh.Position;
                    fh.Position = [currsize(1:2) 900 425];
                    app.axes2.Units = 'Pixels';
                    currsize = app.axes2.OuterPosition;
                    app.axes2.OuterPosition = [currsize(1:2) 950 420];
                    app.axes2.Units = 'Normalized';
            
                    % Title, axis labels and legend
                    app.axes2.FontSize = 14;
                    xlabel(app.axes2,[x_axis ' wavelength (cm^{-1})'],'FontSize',12,'FontWeight','bold');
                    ylabel(app.axes2,'2D signal (a.u.)','FontSize',12,'FontWeight','bold')
                    title(app.axes2,plot_title,'FontSize',10)
                    axis tight
            
                    % Create legend
                    legend(gca);
                    legend('boxoff')
                    legend('Location','eastoutside')
            
                    % Add zero line
                    hline = refline(app.axes2,[0 0]);
                    hline.Color = [0.5 0.5 0.5];
                    set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
                case 'Along several pump WL'
                    switch app.InteractiveModeTick.Value
                        case 1
                            % Select points interactively, to finish hit RETURN
                            handles = SelectTraces(handles,0);
                            % Separate the values into pump and probe, according to how the data is plotted
                            switch plot_pumpdirection
                                case 'Horizontal'
                                    pump_search    = app.SelTraces(:,1);
                                case 'Vertical'
                                    pump_search    = app.SelTraces(:,2);
                            end
                        case 0
                            app.SelTraces = inputdlg('Enter the pump wavenumbers to plot:',...
                                 'Input pump wavenumbers to plot:', [1 60]);
                            pump_search = str2num(app.SelTraces{:})';
                    end
            
                    % Get the data to be plotted
                    % Get the indices of the probe wavelengths
                    pump_indexes        = unique(findClosestId2Val(PumpAxis{PopDelay,1},pump_search));
                    probe_indexes       = 1:length(ProbeAxis);
                    L                   = length(pump_indexes);
                    % Initialise variables
                    data            = zeros(length(ProbeAxis),L);
                    % Get the data
                    for p=1:L
                        data(:,p)       = transpose(PROC_2D_DATA{PopDelay,1}(pump_indexes(p),probe_indexes));
                    end
            
                    if Normalise
                        data            = data./max(abs(data),[],1);
                    end
            
                    % Create a new figure
                    fh = figure();
            
                    % Define the axes
                    app.axes2 = axes(fh);
            
                    % Plot the data
                    cmap=colormap(othercolor('Mrainbow',L));
                    for p=1:L
                       plot(app.axes2,ProbeAxis,data(:,p),'-','LineWidth',2,'MarkerSize',2,'color',cmap(p,:),'DisplayName',[num2str(PumpAxis{PopDelay,1}(pump_indexes(p)),'%.5g') ' cm^{-1}']);
                       hold on
                    end
            
                    % Save plot details
                    plot_title  = ['CUTS ACROSS PUMP WAVELENGTHS AT t_2 = ' num2str(t2delays(PopDelay),'%.3g') ' ps'];
                    x_axis      = 'Probe';
            
                    %%% Nice formatting
                    % Size and colour
                    fh.Color = [1 1 1];
                    currsize = fh.Position;
                    fh.Position = [currsize(1:2) 900 425];
                    app.axes2.Units = 'Pixels';
                    currsize = app.axes2.OuterPosition;
                    app.axes2.OuterPosition = [currsize(1:2) 950 420];
                    app.axes2.Units = 'Normalized';
            
                    % Title, axis labels and legend
                    app.axes2.FontSize = 14;
                    xlabel(app.axes2,[x_axis ' wavelength (cm^{-1})'],'FontSize',14,'FontWeight','bold');
                    ylabel(app.axes2,'2D signal (a.u.)','FontSize',14,'FontWeight','bold')
                    title(app.axes2,plot_title,'FontSize',10)
                    axis tight
            
                    % Create legend
                    legend(gca);
                    legend('boxoff')
                    legend('Location','eastoutside')
            
                    % Add zero line
                    hline = refline(app.axes2,[0 0]);
                    hline.Color = [0.5 0.5 0.5];
                    set(get(get(hline,'Annotation'),'LegendInformation'),'IconDisplayStyle','off')
            end
        end

        % Value changed function: plot_axislegend
        function plot_axislegend_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: plot_colorrange
        function plot_colorrange_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: plot_colourscheme
        function plot_colourscheme_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: plot_contourLineStyle
        function plot_contourLineStyle_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: plot_contourfill
        function plot_contourfill_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: plot_pumpdirection
        function plot_pumpdirection_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end

        % Value changed function: plot_skiplevels
        function plot_skiplevels_Callback(app, event)
            handles = UpdateEdits_2DIR(handles,0);
            plot_2DIR(handles,app.MainAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainGUI = uifigure;
            app.MainGUI.Name = '2D IR data analysis GUI';
            app.MainAxes = uiaxes(app.MainGUI);
            app.MainAxes.Visible = 'off';
            app.BrowseRootDirButton = uibutton(app.MainGUI, 'push');
            app.BrowseRootDirButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRootDirButton_Callback, true);
            app.BrowseRootDirButton.Tooltip = 'Select the root directory to display all data inside it';
            app.BrowseRootDirButton.Text = 'Browse root dir...';
            app.DatafoldersList = uilistbox(app.MainGUI);
            app.DatafoldersList.Items = {'Available data folders...'};
            app.DatafoldersList.ValueChangedFcn = createCallbackFcn(app, @DatafoldersList_Callback, true);
            app.DatafoldersList.Tooltip = 'Select the data to generate a quick preview. It is also possible to use the arrows to look quickly through data. Double-click will enter the selected folder. Use the buttons to go up or refresh.';
            app.DatafoldersList.Value = 'Available data folders...';
            app.CurrDir = uilabel(app.MainGUI);
            app.CurrDir.Text = 'Current root folder will appear here when selected';
            app.uipanel1 = uipanel(app.MainGUI);
            app.uipanel1.Title = 'Plotting controls';
            app.plot_Ncontours = uieditfield(app.uipanel1, 'text');
            app.plot_Ncontours.ValueChangedFcn = createCallbackFcn(app, @plot_Ncontours_Callback, true);
            app.plot_Ncontours.Tooltip = 'Enter the number of contours';
            app.plot_Ncontours.Value = 'Nº Contours';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Text = 'Nº of Contours';
            app.editMaxZ = uieditfield(app.uipanel1, 'text');
            app.editMaxZ.Value = 'Max DeltaAbs';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Text = 'Max. ΔAbs (a.u.)';
            app.maxWL_text = uilabel(app.uipanel1);
            app.maxWL_text.Text = 'Max WL (cm-1)';
            app.timescalemenu = uidropdown(app.uipanel1);
            app.timescalemenu.Items = {'ps', 'fs', 'ns'};
            app.timescalemenu.Tooltip = 'Indicate the units for the measurement (detault = ps). Has only cosmetic effects.';
            app.timescalemenu.Value = 'ps';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Text = 'Timescale ';
            app.minWL_text = uilabel(app.uipanel1);
            app.minWL_text.Text = 'Min WL (cm-1)';
            app.SNRtext = uilabel(app.uipanel1);
            app.SNRtext.Text = 'ω1 res. (cm-1):';
            app.omega1_resolution_text = uilabel(app.uipanel1);
            app.omega1_resolution_text.Text = 'N/A';
            app.Scans_text = uilabel(app.uipanel1);
            app.Scans_text.Text = 'Scans:';
            app.Nscans_number = uilabel(app.uipanel1);
            app.Nscans_number.Text = 'N/A';
            app.plot_Nwhites = uieditfield(app.uipanel1, 'text');
            app.plot_Nwhites.ValueChangedFcn = createCallbackFcn(app, @plot_Nwhites_Callback, true);
            app.plot_Nwhites.Value = '# w';
            app.text29 = uilabel(app.uipanel1);
            app.text29.Text = 'Nº of whites';
            app.ShowContoursTick = uicheckbox(app.uipanel1);
            app.ShowContoursTick.ValueChangedFcn = createCallbackFcn(app, @ShowContoursTick_Callback, true);
            app.ShowContoursTick.Tooltip = 'Tick to show the contour lines';
            app.ShowContoursTick.Text = 'Show contour lines';
            app.plot_contourLineStyle = uieditfield(app.uipanel1, 'text');
            app.plot_contourLineStyle.ValueChangedFcn = createCallbackFcn(app, @plot_contourLineStyle_Callback, true);
            app.plot_contourLineStyle.Tooltip = 'Change the appearance of the contour lines';
            app.plot_contourLineStyle.Value = '-';
            app.plot_pumpdirection = uidropdown(app.uipanel1);
            app.plot_pumpdirection.Items = {'Horizontal', 'Vertical'};
            app.plot_pumpdirection.ValueChangedFcn = createCallbackFcn(app, @plot_pumpdirection_Callback, true);
            app.plot_pumpdirection.Tooltip = 'Select whether the pump axis is the horizontal or vertical axis.';
            app.plot_pumpdirection.Value = 'Horizontal';
            app.text36 = uilabel(app.uipanel1);
            app.text36.Text = 'Pump axis';
            app.plot_axislegend = uidropdown(app.uipanel1);
            app.plot_axislegend.Items = {'Omega', 'Pump-probe', 'ω pump-probe'};
            app.plot_axislegend.ValueChangedFcn = createCallbackFcn(app, @plot_axislegend_Callback, true);
            app.plot_axislegend.Tooltip = 'Select the style for the legend of the contour plots.';
            app.plot_axislegend.Value = 'Omega';
            app.text37 = uilabel(app.uipanel1);
            app.text37.Text = 'Legend';
            app.plot_colorrange = uieditfield(app.uipanel1, 'text');
            app.plot_colorrange.ValueChangedFcn = createCallbackFcn(app, @plot_colorrange_Callback, true);
            app.plot_colorrange.Value = '% rg.';
            app.text38 = uilabel(app.uipanel1);
            app.text38.Text = '% col. rg.';
            app.plot_skiplevels = uieditfield(app.uipanel1, 'text');
            app.plot_skiplevels.ValueChangedFcn = createCallbackFcn(app, @plot_skiplevels_Callback, true);
            app.plot_skiplevels.Tooltip = 'Change the appearance of the contour lines';
            app.plot_skiplevels.Value = '2';
            app.text39 = uilabel(app.uipanel1);
            app.text39.Text = 'every';
            app.text40 = uilabel(app.uipanel1);
            app.text40.Text = 'levels';
            app.plot_contourfill = uicheckbox(app.uipanel1);
            app.plot_contourfill.ValueChangedFcn = createCallbackFcn(app, @plot_contourfill_Callback, true);
            app.plot_contourfill.Tooltip = 'Tick to show the contour lines';
            app.plot_contourfill.Text = 'Filled contours';
            app.plot_contourfill.Value = true;
            app.plot_colourscheme = uidropdown(app.uipanel1);
            app.plot_colourscheme.Items = {'RdOr/Wh/Bl', 'DkRd/Wh/DkBl', 'Rd/Wh/Bl', 'DRd/Ylw/Bl', 'Jet'};
            app.plot_colourscheme.ValueChangedFcn = createCallbackFcn(app, @plot_colourscheme_Callback, true);
            app.plot_colourscheme.Tooltip = 'Select the colormap to use for the contour plot.';
            app.plot_colourscheme.Value = 'RdOr/Wh/Bl';
            app.text42 = uilabel(app.uipanel1);
            app.text42.Text = 'Colour scheme';
            app.text44 = uilabel(app.uipanel1);
            app.text44.Text = 'ω3 res. (cm-1):';
            app.omega3_resolution_text = uilabel(app.uipanel1);
            app.omega3_resolution_text.Text = 'N/A';
            app.SymColRange_tick = uicheckbox(app.uipanel1);
            app.SymColRange_tick.ValueChangedFcn = createCallbackFcn(app, @SymColRange_tick_Callback, true);
            app.SymColRange_tick.Tooltip = 'Tick to show the contour lines';
            app.SymColRange_tick.Text = 'Symmetric colour range';
            app.SymColRange_tick.Value = true;
            app.text46 = uilabel(app.uipanel1);
            app.text46.Text = 'Bin zero:';
            app.binzero_text = uilabel(app.uipanel1);
            app.binzero_text.Text = 'N/A';
            app.CutPlot_tick = uicheckbox(app.uipanel1);
            app.CutPlot_tick.ValueChangedFcn = createCallbackFcn(app, @CutPlot_tick_Callback, true);
            app.CutPlot_tick.Tooltip = 'Tick to show the contour lines';
            app.CutPlot_tick.Text = 'Cut plot';
            app.CutPlot_tick.Value = true;
            app.text49 = uilabel(app.uipanel1);
            app.text49.Text = 'Phase (rad):';
            app.phase_text = uilabel(app.uipanel1);
            app.phase_text.Text = 'N/A';
            app.text51 = uilabel(app.uipanel1);
            app.text51.Text = 'FT size:';
            app.FTsize_text = uilabel(app.uipanel1);
            app.FTsize_text.Text = 'N/A';
            app.EditProbeAxis_tick = uicheckbox(app.uipanel1);
            app.EditProbeAxis_tick.Text = '';
            app.editWLmin = uieditfield(app.uipanel1, 'text');
            app.editWLmin.ValueChangedFcn = createCallbackFcn(app, @editWLmin_Callback, true);
            app.editWLmin.Tooltip = 'Enter desired WL range';
            app.editWLmin.Value = 'WL min';
            app.text53 = uilabel(app.uipanel1);
            app.text53.Text = {'Prb'; 'axs'};
            app.editWLmax = uieditfield(app.uipanel1, 'text');
            app.editWLmax.ValueChangedFcn = createCallbackFcn(app, @editWLmax_Callback, true);
            app.editWLmax.Tooltip = 'Enter desired WL range';
            app.editWLmax.Value = 'WL max';
            app.ShowSpecDiff = uicheckbox(app.uipanel1);
            app.ShowSpecDiff.ValueChangedFcn = createCallbackFcn(app, @ShowSpecDiff_Callback, true);
            app.ShowSpecDiff.Visible = 'off';
            app.ShowSpecDiff.Tooltip = 'Tick to show the contour lines';
            app.ShowSpecDiff.Text = 'Spec. Diff. lines';
            app.ShowSpecDiff.Value = true;
            app.PlotPanel = uipanel(app.MainGUI);
            app.PlotPanel.Title = 'Processing controls';
            app.BkgSubTick = uicheckbox(app.PlotPanel);
            app.BkgSubTick.Tooltip = 'Subtracts the 2D spectrum of the first population delay of the current dataset from all spectra.';
            app.BkgSubTick.Text = 'Subtract scattering (first t₂ delay)';
            app.make2Dplot = uibutton(app.PlotPanel, 'push');
            app.make2Dplot.ButtonPushedFcn = createCallbackFcn(app, @make2Dplot_Callback, true);
            app.make2Dplot.Tooltip = 'Make a 2D contour plot of the current data with the current settings.';
            app.make2Dplot.Text = 'Contour plot';
            app.ApplyChanges = uibutton(app.PlotPanel, 'push');
            app.ApplyChanges.ButtonPushedFcn = createCallbackFcn(app, @ApplyChanges_Callback, true);
            app.ApplyChanges.Tooltip = 'Reprocess the data without reloading and apply all changes';
            app.ApplyChanges.Text = 'Apply changes';
            app.make3Dplot = uibutton(app.PlotPanel, 'push');
            app.make3Dplot.ButtonPushedFcn = createCallbackFcn(app, @make3Dplot_Callback, true);
            app.make3Dplot.Tooltip = 'Make a 3D surface plot of the current data with the current settings';
            app.make3Dplot.Text = 'Surface plot';
            app.plot_Kinetics = uibutton(app.PlotPanel, 'push');
            app.plot_Kinetics.ButtonPushedFcn = createCallbackFcn(app, @plot_Kinetics_Callback, true);
            app.plot_Kinetics.Tooltip = 'Plot the kinetic trace. If Interactive is ON, the user will be requested to clic on the graph to select the wavenumbers, otherwise the user will be asked to enter them manually.';
            app.plot_Kinetics.Text = 'Plot Kinetics';
            app.plot_Slices = uibutton(app.PlotPanel, 'push');
            app.plot_Slices.ButtonPushedFcn = createCallbackFcn(app, @plot_Slices_Callback, true);
            app.plot_Slices.Tooltip = 'Plot the selected slices. Will open a dialog asking which type of slice to plot.';
            app.plot_Slices.Text = 'Plot Slices';
            app.InteractiveModeTick = uicheckbox(app.PlotPanel);
            app.InteractiveModeTick.Tooltip = 'Tick to use interactive mode for plotting and normalisation.';
            app.InteractiveModeTick.Text = 'Interactive';
            app.InteractiveModeTick.Value = true;
            app.Normalise = uicheckbox(app.PlotPanel);
            app.Normalise.Tooltip = 'Tick to normalise the data. Select normalisation point automatically or if interactive is given, will ask for the normalisation point.';
            app.Normalise.Text = 'Normalise';
            app.Probe_Calibration = uicheckbox(app.PlotPanel);
            app.Probe_Calibration.Tooltip = 'Automatically calibrate the probe axis from the first population delay (scattering). If unselected, will try to find a calibrated probe file and use it, otherwhise will use the supplied probe axis.';
            app.Probe_Calibration.Text = 'Auto calibrate probe axis';
            app.PumpCorrection_tick = uicheckbox(app.PlotPanel);
            app.PumpCorrection_tick.Tooltip = 'Tick to divide the 2D spectrum by the pump intensity.';
            app.PumpCorrection_tick.Text = 'Pump Correction';
            app.SVDPanel = uipanel(app.MainGUI);
            app.SVDPanel.Title = 'Phasing, zeropadding and apodisation';
            app.apodise_method = uidropdown(app.SVDPanel);
            app.apodise_method.Items = {'Box', 'Cos', 'Cos^2', 'Cos^3'};
            app.apodise_method.Enable = 'off';
            app.apodise_method.Tooltip = 'Select here the apodisation function to be used.';
            app.apodise_method.Value = 'Cos';
            app.text31 = uilabel(app.SVDPanel);
            app.text31.Enable = 'off';
            app.text31.Text = 'Apodisation';
            app.zeropad_tick = uicheckbox(app.SVDPanel);
            app.zeropad_tick.Enable = 'off';
            app.zeropad_tick.Tooltip = 'Tick to normalise the kinetics/transient spectra';
            app.zeropad_tick.Text = 'Zeropad, factor:';
            app.zeropad_tick.Value = true;
            app.zeropad_next2k = uicheckbox(app.SVDPanel);
            app.zeropad_next2k.Enable = 'off';
            app.zeropad_next2k.Tooltip = 'Tick to zeropad up to the next power of two (for FFT).';
            app.zeropad_next2k.Text = 'to next 2^k';
            app.zeropad_next2k.Value = true;
            app.zeropad_factor = uieditfield(app.SVDPanel, 'text');
            app.zeropad_factor.Enable = 'off';
            app.zeropad_factor.Tooltip = 'Enter here the zeropad factor. Default is 2 (causality in FT). >2 equals interpolation in the pump axis.';
            app.zeropad_factor.Value = '2';
            app.text32 = uilabel(app.SVDPanel);
            app.text32.Enable = 'off';
            app.text32.Text = 'Phase fit';
            app.text34 = uilabel(app.SVDPanel);
            app.text34.Enable = 'off';
            app.text34.Text = '±';
            app.phase_Npoints = uieditfield(app.SVDPanel, 'text');
            app.phase_Npoints.Enable = 'off';
            app.phase_Npoints.Tooltip = 'Enter here the range around the spectral maximum in wavenumbers to consider for fitting the phase.';
            app.phase_Npoints.Value = '100';
            app.phase_method = uidropdown(app.SVDPanel);
            app.phase_method.Items = {'Constant', 'Linear', 'Quadratic', 'Cubic', 'No fit'};
            app.phase_method.Enable = 'off';
            app.phase_method.Value = 'Linear';
            app.text43 = uilabel(app.SVDPanel);
            app.text43.Enable = 'off';
            app.text43.Text = 'cm-1; Coeffs.:';
            app.phase_coeffs = uieditfield(app.SVDPanel, 'text');
            app.phase_coeffs.Enable = 'off';
            app.phase_coeffs.Tooltip = 'Enter here the range around the spectral maximum in wavenumbers to consider for fitting the phase.';
            app.phase_coeffs.Value = '0, 0';
            app.ExitButton = uibutton(app.MainGUI, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_Callback, true);
            app.ExitButton.Icon = 'ExitButton_image.png';
            app.ExitButton.Text = '';
            app.AnalysisPanel = uipanel(app.MainGUI);
            app.AnalysisPanel.Title = 'Analysis';
            app.PowerDependence = uibutton(app.AnalysisPanel, 'push');
            app.PowerDependence.Enable = 'off';
            app.PowerDependence.Visible = 'off';
            app.PowerDependence.Text = 'Power Dep.';
            app.SubtractSpectra = uibutton(app.AnalysisPanel, 'push');
            app.SubtractSpectra.ButtonPushedFcn = createCallbackFcn(app, @SubtractSpectra_Callback, true);
            app.SubtractSpectra.Enable = 'off';
            app.SubtractSpectra.Text = 'Subtract spectra';
            app.Fit = uibutton(app.AnalysisPanel, 'push');
            app.Fit.ButtonPushedFcn = createCallbackFcn(app, @Fit_Callback, true);
            app.Fit.Enable = 'off';
            app.Fit.Text = 'Fit';
            app.ShiftT2 = uibutton(app.AnalysisPanel, 'push');
            app.ShiftT2.Enable = 'off';
            app.ShiftT2.Visible = 'off';
            app.ShiftT2.Text = 'Shift t₂';
            app.SpectralDiffusion = uibutton(app.AnalysisPanel, 'push');
            app.SpectralDiffusion.ButtonPushedFcn = createCallbackFcn(app, @SpectralDiffusion_Callback, true);
            app.SpectralDiffusion.Enable = 'off';
            app.SpectralDiffusion.Text = 'Spectral diffusion';
            app.IntegralDynamics = uibutton(app.AnalysisPanel, 'push');
            app.IntegralDynamics.ButtonPushedFcn = createCallbackFcn(app, @IntegralDynamics_Callback, true);
            app.IntegralDynamics.Enable = 'off';
            app.IntegralDynamics.Text = 'Integral dynamics';
            app.Refresh = uibutton(app.MainGUI, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @Refresh_Callback, true);
            app.Refresh.Icon = 'Refresh_image.png';
            app.Refresh.Tooltip = 'Update the contents of the current folder';
            app.Refresh.Text = '.';
            app.UpDirectory = uibutton(app.MainGUI, 'push');
            app.UpDirectory.ButtonPushedFcn = createCallbackFcn(app, @UpDirectory_Callback, true);
            app.UpDirectory.Icon = 'UpDirectory_image.png';
            app.UpDirectory.Tooltip = 'Go up one directory';
            app.UpDirectory.Text = '';
            app.DataTypeMenu = uidropdown(app.MainGUI);
            app.DataTypeMenu.Items = {'2D IR (Lab 1/Lab 4 new)', '2D IR (Lab 4 old)', 'Transient 2D IR', '2D IR (Lab 3)'};
            app.DataTypeMenu.ValueChangedFcn = createCallbackFcn(app, @DataTypeMenu_Callback, true);
            app.DataTypeMenu.Value = '2D IR (Lab 1/Lab 4 new)';
            app.Population_delay = uilistbox(app.MainGUI);
            app.Population_delay.Items = {'Choose a delay...'};
            app.Population_delay.ValueChangedFcn = createCallbackFcn(app, @Population_delay_Callback, true);
            app.Population_delay.Value = 'Choose a delay...';
            app.text41 = uilabel(app.MainGUI);
            app.text41.Text = 'Population delay (ps)';
            app.SaveProbeCal = uibutton(app.MainGUI, 'push');
            app.SaveProbeCal.ButtonPushedFcn = createCallbackFcn(app, @SaveProbeCal_Callback, true);
            app.SaveProbeCal.Enable = 'off';
            app.SaveProbeCal.Text = 'Save probe cal.';
            app.VersionText = uilabel(app.MainGUI);
            app.VersionText.Text = 'Version text';
            app.GoTodayDir = uibutton(app.MainGUI, 'push');
            app.GoTodayDir.ButtonPushedFcn = createCallbackFcn(app, @GoTodayDir_Callback, true);
            app.GoTodayDir.Tooltip = 'Select the root directory to display all data inside it';
            app.GoTodayDir.Text = 'Go to today...';
            app.Transient2D_mode = uidropdown(app.MainGUI);
            app.Transient2D_mode.Items = {'Spectrum 1 (UV off)', 'Spectrum 2 (UV on)', 'Difference (1-2)', 'Difference (2-1)', 'Sum'};
            app.Transient2D_mode.ValueChangedFcn = createCallbackFcn(app, @Transient2D_mode_Callback, true);
            app.Transient2D_mode.Visible = 'off';
            app.Transient2D_mode.Value = 'Spectrum 1 (UV off)';
            app.Transient2D_text = uilabel(app.MainGUI);
            app.Transient2D_text.Visible = 'off';
            app.Transient2D_text.Text = 'For transient 2D IR, plot:';
            app.text58 = uilabel(app.MainGUI);
            app.text58.Text = {'© 2018'; 'Ricardo J. Fernández-Terán'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = InterfDataAnalysis_GUI_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainGUI)

            % Execute the startup function
            runStartupFcn(app, @InterfDataAnalysis_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainGUI)
        end
    end
end