classdef KineticsFitWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrokineticFittingUIFigure  matlab.ui.Figure
        GaussianIRFCheckBox            matlab.ui.control.CheckBox
        pFit_to_p0_Button              matlab.ui.control.Button
        Chi2Value                      matlab.ui.control.NumericEditField
        Chi2Value_label                matlab.ui.control.Label
        CopyTaus_button                matlab.ui.control.Button
        FitMethod                      matlab.ui.control.DropDown
        DefaultConcCheckBox            matlab.ui.control.CheckBox
        RestrictFitCheckBox            matlab.ui.control.CheckBox
        IRFtable                       matlab.ui.control.Table
        Model_pFIDCheckBox             matlab.ui.control.CheckBox
        VersionLabel                   matlab.ui.control.Label
        ExplorerModeButton             matlab.ui.control.StateButton
        PP_PlotsandcutsPanel           matlab.ui.container.Panel
        PlotsAndCuts                   matlab.ui.container.GridLayout
        PP_SavetracesSwitch            matlab.ui.control.CheckBox
        PP_IncludeResiduals            matlab.ui.control.CheckBox
        PP_InteractivemodeSwitch       matlab.ui.control.CheckBox
        PP_NormaliseCheckBox           matlab.ui.control.CheckBox
        PP_PlotTrSpectraButton         matlab.ui.control.Button
        PP_PlotKineticsButton          matlab.ui.control.Button
        PP_SurfaceplotButton           matlab.ui.control.Button
        PP_ContourplotButton           matlab.ui.control.Button
        AddTracestoLivePlotsButton     matlab.ui.control.Button
        ExtraPlotsAfterFit_CheckBox    matlab.ui.control.CheckBox
        DefinemodelButton              matlab.ui.control.Button
        FitOptionsButton               matlab.ui.control.Button
        PP_PlotcontrolsPanel           matlab.ui.container.Panel
        PP_NContours                   matlab.ui.control.NumericEditField
        NofcontoursEditFieldLabel      matlab.ui.control.Label
        PP_ColourScheme                matlab.ui.control.DropDown
        ColourschemeDropDownLabel      matlab.ui.control.Label
        PP_RestoredefaultlimitsButton  matlab.ui.control.Button
        PP_MaxDeltaAbs                 matlab.ui.control.EditField
        MaxAbsmODLabel                 matlab.ui.control.Label
        PP_DeltaAbsPlotRange           matlab.ui.control.EditField
        AbsrangeEditFieldLabel         matlab.ui.control.Label
        PP_NWhites                     matlab.ui.control.NumericEditField
        NofwhitelevelsLabel            matlab.ui.control.Label
        PP_MinTime                     matlab.ui.control.NumericEditField
        MintimeLabel                   matlab.ui.control.Label
        PP_MaxTime                     matlab.ui.control.NumericEditField
        MaxtimeLabel                   matlab.ui.control.Label
        PP_MaxWL                       matlab.ui.control.NumericEditField
        MaxWLEditFieldLabel            matlab.ui.control.Label
        PP_MinWL                       matlab.ui.control.NumericEditField
        MinWLEditFieldLabel            matlab.ui.control.Label
        PP_PlotScaleSlider             matlab.ui.control.Slider
        ZScaleLabel                    matlab.ui.control.Label
        PP_LogTime_tick                matlab.ui.control.CheckBox
        PP_FilledContours_tick         matlab.ui.control.CheckBox
        PP_SymmetricColourScale        matlab.ui.control.CheckBox
        PP_PlotScaleNumber             matlab.ui.control.NumericEditField
        PP_ContourFormat               matlab.ui.control.EditField
        PP_NskipContours               matlab.ui.control.NumericEditField
        PP_ShowContours_tick           matlab.ui.control.CheckBox
        RemoveComponentButton          matlab.ui.control.Button
        AddComponentButton             matlab.ui.control.Button
        ResetFitButton                 matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.Button
        RateFitTable                   matlab.ui.control.Table
        ModelSelectionButtonGroup      matlab.ui.container.ButtonGroup
        ODEdefinedButton               matlab.ui.control.ToggleButton
        TargetButton                   matlab.ui.control.ToggleButton
        SequentialButton               matlab.ui.control.ToggleButton
        ParallelButton                 matlab.ui.control.ToggleButton
        SaveButton                     matlab.ui.control.Button
        FITDATAButton                  matlab.ui.control.Button
        DataLoadedLamp                 matlab.ui.control.Lamp
        DataLoadedLampLabel            matlab.ui.control.Label
        ClearDataButton                matlab.ui.control.Button
        PlotWhatButtonGroup            matlab.ui.container.ButtonGroup
        ShowResidualsButton            matlab.ui.control.ToggleButton
        ShowFitButton                  matlab.ui.control.ToggleButton
        ShowDataButton                 matlab.ui.control.ToggleButton
        LoadPDATButton                 matlab.ui.control.Button
        IRFandtimezeroPanel            matlab.ui.container.Panel
        SpectralAxes                   matlab.ui.control.UIAxes
        KineticsAxes                   matlab.ui.control.UIAxes
        MainAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PP_Data      = []; % Container for Pump-Probe Data
        KineticModel = []; % Structure containing the parameters of the kinetic model
        FitOptions   = []; % Structure containing the fit options (i.e. convergence, etc.)
    end
    
    properties (Access = private)
        AppVer = 'v3.1b / 2026.02.24 / Ricardo J. Fernández-Terán' % Description
    end
    
    methods (Access = public)
        
        function UpdatePlot_PP(app,PlotAxes)
            switch app.PP_LogTime_tick.Value
                case 0
                    app.PP_Data.linlog = 'lin';
                case 1
                    app.PP_Data.linlog = 'log';
            end
            
            switch app.PlotWhatButtonGroup.SelectedObject.Text
                case 'Data'
                    plotWhat = app.PP_Data.rawsignal;
                case 'Fit'
                    plotWhat = app.PP_Data.FitData;
                case 'Residuals'
                    plotWhat = app.PP_Data.ResData;
            end
            app.PP_Data.rawcorr='RAW';
            plot2D(app,plotWhat,PlotAxes,'On',1,true);
        end
    end
    
    methods (Access = private)
        
        function ShowControls(app,State)
            switch State
                case 1
                    st='on';
                case 0
                    st='off';
            end

            app.ClearDataButton.Enable          = st;
            app.PlotWhatButtonGroup.Enable      = st;
            app.PlotWhatButtonGroup.Visible     = st;
                app.ShowDataButton.Enable       = 'on';
                app.ShowResidualsButton.Enable  = 'off';
                app.ShowFitButton.Enable        = 'off';
            app.ModelSelectionButtonGroup.Enable    = st;
            app.ModelSelectionButtonGroup.Visible   = st;
            app.FitMethod.Visible               = st;
            
            app.RateFitTable.Visible            = st;
            app.RateFitTable.Enable             = st;
            
            app.IRFtable.Visible                = st;
            app.IRFtable.Enable                 = st;

            app.PreviewButton.Visible           = st;
            app.FITDATAButton.Visible           = st;
            
            app.SaveButton.Visible              = 'off';
            app.ResetFitButton.Visible          = 'off';
            app.CopyTaus_button.Visible         = 'off';
            app.pFit_to_p0_Button.Visible       = 'off';

            app.SaveButton.Enable               = 'off';
            app.ResetFitButton.Enable           = 'off';
            app.CopyTaus_button.Enable          = 'off';
            app.pFit_to_p0_Button.Enable        = 'off';
            
            app.Chi2Value.Visible               = 'off';
            app.Chi2Value_label.Visible         = 'off';

            app.RestrictFitCheckBox.Visible     = st;     

            app.AddComponentButton.Visible      = st;
            app.RemoveComponentButton.Visible   = st;
            
            app.GaussianIRFCheckBox.Visible     = st;
            app.IRFandtimezeroPanel.Visible     = st;
            app.FitOptionsButton.Visible        = st;

            app.PP_PlotcontrolsPanel.Visible    = st;
            app.PP_PlotsandcutsPanel.Visible    = st;
            app.ExplorerModeButton.Visible      = st;
            app.ExplorerModeButton.Value        = 0;
            app.PP_IncludeResiduals.Enable      = 'off';

            app.AddTracestoLivePlotsButton.Visible=st;
            app.ExtraPlotsAfterFit_CheckBox.Visible=st;

            app.MainAxes.Visible        = st;
            cla(app.SpectralAxes,'reset');
            cla(app.KineticsAxes,'reset');
            app.SpectralAxes.Visible    = 'off';
            app.KineticsAxes.Visible    = 'off';
        end
        
        function UpdateInfo_PP(app,mode)
            k = 1;
            switch mode
                case 'Write'
                    plotranges = app.PP_Data.plotranges{k};
                    app.PP_MinTime.Value            = max(plotranges(1),0.2);
                    app.PP_MaxTime.Value            = plotranges(2);
                    app.PP_MinWL.Value              = plotranges(3);
                    app.PP_MaxWL.Value              = plotranges(4);
                    app.PP_MaxDeltaAbs.Value        = [num2str(plotranges(5),'%.3f') ', ' num2str(plotranges(6),'%.3f')];
                    app.PP_NContours.Value          = plotranges(7);
                    plotPercent                     = app.PP_PlotScaleSlider.Value/100;
                    zminmax                         = round(max([abs(plotranges(5)) abs(plotranges(6))]),3);
                    if app.PP_SymmetricColourScale.Value == 1
                        minplot = -zminmax*plotPercent;
                        maxplot = +zminmax*plotPercent;
                    else
                        minplot = plotranges(5)*plotPercent;
                        maxplot = plotranges(6)*plotPercent;
                    end
                    app.PP_DeltaAbsPlotRange.Value  = [num2str(minplot,'%.3f') ', ' num2str(maxplot,'%.3f')];
                case 'Read'
                    plotranges                      = app.PP_Data.plotranges{k};
                    plotranges(1)                   = app.PP_MinTime.Value;
                    plotranges(2)                   = app.PP_MaxTime.Value;
                    plotranges(3)                   = app.PP_MinWL.Value;
                    plotranges(4)                   = app.PP_MaxWL.Value;
                    plotPercent                     = app.PP_PlotScaleSlider.Value/100;
                    zminmax                         = round(max([abs(plotranges(5)) abs(plotranges(6))]),3);
                    if app.PP_SymmetricColourScale.Value == 1
                        minplot = -zminmax*plotPercent;
                        maxplot = +zminmax*plotPercent;
                    else
                        minplot = plotranges(5)*plotPercent;
                        maxplot = plotranges(6)*plotPercent;
                    end
                    app.PP_DeltaAbsPlotRange.Value  = [num2str(minplot,'%.3f') ', ' num2str(maxplot,'%.3f')];
                    app.PP_Data.plotranges{k}       = plotranges;
            end
        end
        
        function ResetRatesTable(app)
            app.RateFitTable.Data = [{1,Inf,0,false};{10,Inf,0,false}];
            app.RateFitTable.RowName={'1'; '2'};
            app.RateFitTable.ColumnName = {['Tau (' app.PP_Data.timescale ')']; 'UB'; 'LB'; 'Fix?'};
        end
        
        function ResetIRFtable(app)
            app.IRFtable.Data       = [{0,0.5,-0.5,true};{0.2,0.5,0.05,true}];
            app.IRFtable.RowName    = {'t0'; 'Δ'};
            app.IRFtable.ColumnName = {['Val. (' app.PP_Data.timescale ')']; 'UB'; 'LB'; 'Fix?'};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','MATLAB:Axes:NegativeLimitsInLogAxis');
            app.VersionLabel.Text = app.AppVer;
        end

        % Button pushed function: LoadPDATButton
        function LoadPDATButtonPushed(app, event)
            %% Clear Everything just in case
            cla(app.MainAxes,'reset');
            cla(app.SpectralAxes,'reset');
            cla(app.KineticsAxes,'reset');
            ShowControls(app,0);
            app.DataLoadedLamp.Color = [0.8 0.8 0.8];
            app.PP_Data         = [];
            app.KineticModel    = [];
            
            app.ShowDataButton.Value = 1;

            app.Model_pFIDCheckBox.Visible = 'off';
            app.Model_pFIDCheckBox.Value   = 0;

            %% Now load new data
            [datafilename,rootdir] = uigetfile({'*.pdat','Processed DAT files (PDAT)'},'Select Dataset to Analyse...','MultiSelect','off');
            if datafilename == 0
                warning('No file selected. Aborting.')
                ShowControls(app,0);
                return
            end
            
            try
                cla(app.MainAxes,'reset');
                cla(app.SpectralAxes,'reset');
                cla(app.KineticsAxes,'reset');
                ShowControls(app,0);
                app.DataLoadedLamp.Color = [0.8 0.8 0.8];
                app.PP_Data         = [];
                app.KineticModel    = [];
                
                app.ShowDataButton.Value = 1;

                app.DataLoadedLamp.Color    = [0.75 0.75 0];
                app.PP_Data.recalcBkg       = 0 ;
                app.PP_Data.datafilename    = datafilename;
                app.PP_Data.rootdir         = rootdir;
                app.PP_Data = LoadPDAT(app.PP_Data);
                app.DataLoadedLamp.Color    = [0 1 0];
                % Initialise defaults and update the Edits for the first time ('Write'):
                UpdateInfo_PP(app,'Write');
                UpdatePlot_PP(app,app.MainAxes);
                ShowControls(app,1);
                ResetRatesTable(app);
                ResetIRFtable(app);
                % If dataset is IR, enable pFID modelling
                switch app.PP_Data.probeunits
                    case 'Wavenumbers'
                        app.Model_pFIDCheckBox.Visible = 'on';
                    otherwise 
                        app.Model_pFIDCheckBox.Visible = 'off';
                end
            catch err
                app.DataLoadedLamp.Color = [1 0 0];
                warning(err.message)
                ShowControls(app,0);
                app.Model_pFIDCheckBox.Visible = 'off';
                return
            end

            app.Model_pFIDCheckBox.Value = 0;
            app.Chi2Value.Value = 0;
            figure(app.SpectrokineticFittingUIFigure);
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            cla(app.MainAxes,'reset');
            cla(app.SpectralAxes,'reset');
            cla(app.KineticsAxes,'reset');
            ShowControls(app,0);
            app.DataLoadedLamp.Color = [0.8 0.8 0.8];
            app.PP_Data         = [];
            app.KineticModel    = [];
            
            app.ShowDataButton.Value = 1;

            app.Model_pFIDCheckBox.Visible = 'off';
            app.Model_pFIDCheckBox.Value   = 0;
        end

        % Callback function: PP_ColourScheme, PP_ContourFormat, 
        % ...and 14 other components
        function PP_UpdatedPlotDetails(app, event)
            app.PP_PlotScaleSlider.Value = app.PP_PlotScaleNumber.Value;
            UpdateInfo_PP(app,'Read')
            UpdatePlot_PP(app,app.MainAxes)
        end

        % Button pushed function: PP_RestoredefaultlimitsButton
        function PP_RestoredefaultlimitsButtonPushed(app, event)
            k   = 1;
            
            delays      = app.PP_Data.delays;
            cmprobe     = app.PP_Data.cmprobe{k};
            rawsignal   = app.PP_Data.rawsignal{k};
            %% Read the plot ranges
            mintime     = min(delays);
            maxtime     = max(delays);
            minabs      = min(rawsignal(:));
            maxabs      = max(rawsignal(:));
            minwl       = min(cmprobe);
            maxwl       = max(cmprobe);
            Ncontours   = 40; % 40 contours by default is OK
            
            app.PP_Data.plotranges{k}  = [mintime maxtime minwl maxwl minabs maxabs Ncontours];
            app.PP_LogTime_tick.Value  = true;
            
            UpdateInfo_PP(app,'Write');
            
            app.PP_PlotScaleNumber.Value = 100;
            app.PP_PlotScaleSlider.Value = 100;
            
            UpdatePlot_PP(app,app.MainAxes);
        end

        % Value changing function: PP_PlotScaleSlider
        function PP_PlotScaleSliderValueChanging(app, event)
            app.PP_PlotScaleNumber.Value = event.Value;
        end

        % Value changed function: GaussianIRFCheckBox
        function GaussianIRFCheckBoxValueChanged(app, event)
            switch app.GaussianIRFCheckBox.Value
                case 1
                    app.IRFtable.Data(2,:) = {0.2,0.5,0.05,false};
                case 0
                    app.IRFtable.Data(2,:) = [];
            end
            
            
        end

        % Button pushed function: RemoveComponentButton
        function RemoveComponentButtonPushed(app, event)
            if size(app.RateFitTable.Data,1) > 1
                app.RateFitTable.Data(end,:) = [];
                app.RateFitTable.RowName(end)= [];
            end
            if size(app.RateFitTable.Data,1) < 2
                app.RemoveComponentButton.Enable = 'off';
            end
        end

        % Button pushed function: AddComponentButton
        function AddComponentButtonPushed(app, event)
            oldData = app.RateFitTable.Data;
            nTau    = size(app.RateFitTable.Data,1);
            lastK   = cell2mat(app.RateFitTable.Data(end,1:3));
            newK    = lastK*10;

            app.RateFitTable.Data = [oldData; [num2cell(newK) {false}]];

            app.RateFitTable.RowName = [app.RateFitTable.RowName; {num2str(nTau+1)}];
            if nTau+1 >= 2
                app.RemoveComponentButton.Enable = 'on';
            end
        end

        % Selection changed function: ModelSelectionButtonGroup
        function ModelSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelSelectionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case {'Target','ODE-defined'}
                    app.DefinemodelButton.Visible       = 'on';
                    app.AddComponentButton.Visible      = 'off';
                    app.RemoveComponentButton.Visible   = 'off';
                    app.RateFitTable.Data = "Define model first...";
                    app.DefaultConcCheckBox.Visible     = 'on';
                    app.DefaultConcCheckBox.Value       = 1;
                otherwise
                    app.DefinemodelButton.Visible       = 'off';
                    app.AddComponentButton.Visible      = 'on';
                    app.RemoveComponentButton.Visible   = 'on';
                    app.RemoveComponentButton.Enable    = 'on';
                    app.DefaultConcCheckBox.Visible     = 'off';
                    app.DefaultConcCheckBox.Value       = 0;
                    ResetRatesTable(app);  
            end
            app.KineticModel = [];
        end

        % Button pushed function: DefinemodelButton
        function DefinemodelButtonPushed(app, event)
            switch app.ModelSelectionButtonGroup.SelectedObject.Text
                case 'Target'
                    % Ask user to select a special target model
                    % (sequential models are not considered here, it's easier to make them general)
                    ModelList = {
                        '[1] A <=> B',...
                        '[2] A <=> B; A -> ; B -> ',...
                        '[3] A <=> B; A -> ',...
                        '[4] A <=> B; B -> ',...
                        '[5] A <=> B; A -> C; B -> ',...
                        '[6] A <=> B; B<=>C; C ->',...
                        '[7] A -> B; B<=>C; B ->; C ->',...
                        '[8] A -> B -> D;  A -> C -> D; D ->'...
                        '[9] A -> B -> C -> ; A -> D -> E ->'...
                        '[10] A -> B -> C; A -> D -> E'...
                        '[11] A -> B -> ; A -> C ->'...
                        '[12] A -> B -> C<=>D; C ->; D ->'...
                        '[13] Define Custom Model...'};
                    [modelIdx,tf] = listdlg('ListString',ModelList,'PromptString','Select a target model...','SelectionMode','single','ListSize',[200 300],'Name','Target Model');
                    
                    if tf == 0
                        warning('Aborting, no model selected :(')
                        figure(app.SpectrokineticFittingUIFigure);
                        return
                    end

                    [Kmat_Fun,numTaus,numC,defTbl,defRowNames]  = ParseKmatrix(modelIdx);
                    
                    VisualiseKMat(Kmat_Fun,numTaus,numC,app.ModelSelectionButtonGroup.SelectedObject.Text);
                    
                    app.RateFitTable.Data   = defTbl;
                    app.RateFitTable.RowName= defRowNames;
                    
                    %% Ask user for the initial concentrations
                    defC0 = [1 zeros(1,numC-1)];
                    
                    app.KineticModel.TargetModel= ModelList{modelIdx};
                    app.KineticModel.Kmat_Fun   = Kmat_Fun;
                    app.KineticModel.numTaus    = numTaus;
                    app.KineticModel.numC       = numC;
                    app.KineticModel.C0         = defC0;
                case 'ODE-defined'
                    warndlg('Not implemented yet!');
                    return
%                     prompt = 'Enter K matrix in functional form [i.e. as a function of a vector "k" containing the taus]:';
%                     title  = 'Define K matrix for target model';
%                     dims   = [50 100];
%                     definput = ["@(k)  [-k(1)-k(2) k(3)" + ...
%                         "k(1) -k(2)-k(3)];"];
            end
            figure(app.SpectrokineticFittingUIFigure);

        end

        % Button pushed function: FITDATAButton, PreviewButton
        function DoFitOrPreview_Function(app, event)
            switch event.Source.Text % 'Preview' or 'FIT DATA'
                case 'Preview'
                    doFit = 0;
                case 'FIT DATA'
                    doFit = 1;
            end

            nTau        = size(app.RateFitTable.Data,1);
            modelType   = app.ModelSelectionButtonGroup.SelectedObject.Text;
            switch modelType
                case {'Parallel','Sequential'}
                    [Kmat_Fun,numTaus,defC0]    = ParSeqModels(nTau,modelType);
                    app.KineticModel.Kmat_Fun   = Kmat_Fun;
                    app.KineticModel.numTaus    = numTaus;
                    app.KineticModel.numC       = nTau;
                    app.KineticModel.C0         = defC0;
                    
                    VisualiseKMat(Kmat_Fun,numTaus,nTau,modelType);
                case {'Target','ODE-defined'}
                    % app.KineticModel structure should already exist, otherwise the user needs to enter it, so warn them
                    if ~isstruct(app.KineticModel)
                        errordlg('No target or ODE model has been chosen! Please click on "Define Model..." and try again.','No model');
                        return
                    end
            end
            
            % Read IRF settings
            app.KineticModel.IRF.GauIRF = app.GaussianIRFCheckBox.Value;
            app.KineticModel.IRF.Values = app.IRFtable.Data;
            
            % Fix the taus that are set to Inf to begin with
            InfTaus = isinf(cell2mat(app.RateFitTable.Data(:,1)));
            app.RateFitTable.Data(InfTaus,4) = {true};

            % Read initial rates
            app.KineticModel.Tau0       = app.RateFitTable.Data;
            app.KineticModel.modelType  = modelType;
            
            % Decide whether to make extra plots or not (Data+Fit+Res contour plots)
            ExtraPlots = app.ExtraPlotsAfterFit_CheckBox.Value;
            % Decide whether to model the perturbed free induction decay or not            
            Model_pFID = app.Model_pFIDCheckBox.Value;
            % Decide whether to cut the data according to current plot limits
            CutData    = app.RestrictFitCheckBox.Value;
            % Decide whether to normalise the SAS
            NormaliseSAS = app.PP_NormaliseCheckBox.Value;
            % Decide which metehod to use to do the fit
            Method = app.FitMethod.Value;
            
            %%%%%%%%%%%%%% Pass everything over to the Fit Handler
            [Dbest,res,CConc,Sfit,FitStruct] = fitHandler(doFit,app.PP_Data,app.KineticModel,ExtraPlots,Model_pFID,CutData,NormaliseSAS,Method);
            
            % Store fitted parameters in the structure
            app.KineticModel.FitStruct          = FitStruct;
            
            % Show current chi^2 value
            app.Chi2Value.Value                 = FitStruct.chi2;
            app.Chi2Value.Visible               = 'on';
            app.Chi2Value_label.Visible         = 'on';

            app.SaveButton.Visible              = 'on';
            app.SaveButton.Enable               = 'on';
            app.ResetFitButton.Visible          = 'on';
            app.ResetFitButton.Enable           = 'on';

            app.ShowFitButton.Enable            = 'on';
            app.ShowResidualsButton.Enable      = 'on';
            app.PP_IncludeResiduals.Enable      = 'on';
            
            app.CopyTaus_button.Visible         = 'on';
            app.CopyTaus_button.Enable          = 'on';

            app.pFit_to_p0_Button.Visible       = 'on';
            app.pFit_to_p0_Button.Enable        = 'on';

            app.PP_Data.FitData = {Dbest};
            app.PP_Data.ResData = {res};
            app.PP_Data.ConcProf= CConc;
            app.PP_Data.Sfit    = Sfit;

            if app.ShowResidualsButton.Value ~= 1
                app.ShowFitButton.Value = 1;
            end
            
            UpdateInfo_PP(app,'Read')
            UpdatePlot_PP(app,app.MainAxes)
            figure(app.SpectrokineticFittingUIFigure);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %% Save fitted parameters
            Dbest       = app.PP_Data.FitData{1};
            res         = app.PP_Data.ResData{1};
            CConc       = app.PP_Data.ConcProf;
            Sfit        = app.PP_Data.Sfit;
            Dexp        = app.PP_Data.rawsignal{1};
            
            t           = app.PP_Data.delays;
            WL          = app.PP_Data.cmprobe{1};
            timescale   = app.PP_Data.timescale;
            Xunits      = app.PP_Data.Xunits;

            pFit      	= app.KineticModel.FitStruct.pFit;
            pErr      	= app.KineticModel.FitStruct.pErr;
            GauIRF    	= app.KineticModel.FitStruct.GauIRF;
            FixTAU    	= app.KineticModel.FitStruct.FixTAU;
            FixIRF    	= app.KineticModel.FitStruct.FixIRF;
            IsFixTau  	= app.KineticModel.FitStruct.IsFixTau;
            IsFixIRF  	= app.KineticModel.FitStruct.IsFixIRF;
            chi2      	= app.KineticModel.FitStruct.chi2;
            FitDone     = app.KineticModel.FitStruct.FitDone;
            tSel      	= app.KineticModel.FitStruct.tSel;
            WLSel     	= app.KineticModel.FitStruct.WLSel;
            
            Kmat_Fun    = app.KineticModel.Kmat_Fun;
            numTaus     = app.KineticModel.numTaus;
            numC        = app.KineticModel.numC;  
            C0          = app.KineticModel.C0;        
            Tau0        = app.KineticModel.Tau0;     
            modelType   = app.KineticModel.modelType;
            
            [~,dfn,~] = fileparts(app.PP_Data.datafilename);
            save_fn = [app.PP_Data.rootdir filesep dfn '_fit' datestr(now,'yyyymmdd_hhMMss') '.mat'];
            % save(save_fn, 't_fit', 'WL_fit', 'Dbest', 'res', 'CConc', 'Sfit', 'FitStruct')
            save(save_fn, '-regexp', '^(?!(app|event|save_fn|dfn)$).', '-v6')
            disp('Saved .MAT file with fit parameters.')
        end

        % Button pushed function: ResetFitButton
        function ResetFitButtonPushed(app, event)
            modelType = app.ModelSelectionButtonGroup.SelectedObject.Text;
            switch modelType
                case {'Target','ODE-defined'}
                    app.DefinemodelButton.Visible       = 'on';
                    app.AddComponentButton.Visible      = 'off';
                    app.RemoveComponentButton.Visible   = 'off';
                    app.RateFitTable.Data = "Define model first...";
                otherwise
                    app.DefinemodelButton.Visible       = 'off';
                    app.AddComponentButton.Visible      = 'on';
                    app.RemoveComponentButton.Visible   = 'on';
                    app.RemoveComponentButton.Enable    = 'on';
                    ResetRatesTable(app);  
            end
            app.KineticModel = [];
            app.Chi2Value.Value = 0;
        end

        % Button pushed function: PP_PlotTrSpectraButton
        function PP_PlotTrSpectraButtonPushed(app, event)
            DET                     = 1; % Only 1 detector since we're working with PDAT files
            interactive             = app.PP_InteractivemodeSwitch.Value;
            app.PP_Data.SelTraces   = [];
            
            switch interactive
                case 1
                    % Patch because ginput doesn't work on UIFigures
                    fh = figure;
                    fh.Position = app.MainAxes.Position;
                    fh.Position(1:2) = fh.Position(1:2) + app.SpectrokineticFittingUIFigure.Position(1:2);
                    newaxes = axes('Parent',fh);
                    UpdatePlot_PP(app,newaxes);
                    
                    % Select traces interactively, to finish hit RETURN
                    try 
                        [app.PP_Data,aborted] = SelectTraces(app.PP_Data);
                        if aborted == 1
                            return
                        end
                    catch
                        % Do nothing
                    end
                    
                    % Delete the extra figure
                    if ishandle(fh)
                        close(fh)  
                    end
                    
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                    
                    % Store values into a single column (!)
                    app.PP_Data.SelTraces = app.PP_Data.SelTraces(:,2); % column 2 = Time, make it the only column
                case 0
                    timescale = app.PP_Data.timescale;
                    app.PP_Data.SelTraces = inputdlg('Enter space-separated times to plot the transient spectra:',...
                         ['Input desired times (in ',timescale,')'], [1 50],{'0.2 0.5 1 2 5 10 20 50 100 200 500 1000 2000 5000'});
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                    app.PP_Data.SelTraces = str2num(app.PP_Data.SelTraces{:})';
                    app.PP_Data.SelTraces = sort(app.PP_Data.SelTraces,1);
            end
            % Check if RAW or CORR and give DATA
            switch app.PP_Data.rawcorr
                case 'RAW'
                    Zdata = app.PP_Data.rawsignal{DET}';
                case 'CORRECTED'
                    Zdata = app.PP_Data.corrdata{DET}';
            end
            
            %%% Call plotting subroutine
            [~,k,ydata] = plot_TransientSpectra(app.PP_Data,DET,Zdata,2,6,app.PP_NormaliseCheckBox.Value,0,app.PP_IncludeResiduals.Value);

            % Decide whether to save the plotted traces or not
            if strcmp(app.PP_SavetracesSwitch.Value,'On')
                filename    = [app.PP_Data.rootdir filesep app.PP_Data.datafilename '_Sp-traces_DET' num2str(DET) '.dat'];
                probe = app.PP_Data.cmprobe{DET};
                writematrix([[0;app.PP_Data.delays(k)],[probe(:)';ydata']]',filename,'Delimiter','\t')
                helpdlg(["Saved traces to:";filename],'Traces have been saved!')
            end
        end

        % Button pushed function: PP_PlotKineticsButton
        function PP_PlotKineticsButtonPushed(app, event)
            DET                     = 1; % Only 1 detector since we're working with PDAT files
            interactive             = app.PP_InteractivemodeSwitch.Value;
            app.PP_Data.SelTraces   = [];
            
            switch interactive
                case 1
                    % Patch because ginput doesn't work on UIFigures
                    fh = figure;
                    fh.Position = app.MainAxes.Position;
                    fh.Position(1:2) = fh.Position(1:2) + app.SpectrokineticFittingUIFigure.Position(1:2);
                    newaxes = axes('Parent',fh);
                    UpdatePlot_PP(app,newaxes);
                    
                    % Select traces interactively, to finish hit RETURN
                    try 
                        [app.PP_Data,aborted] = SelectTraces(app.PP_Data);
                        if aborted == 1
                            return
                        end
                    catch
                        % Do nothing
                    end
                    
                    % Delete the extra figure
                    if ishandle(fh)
                        close(fh)  
                    end
                    
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                case 0
                    app.PP_Data.SelTraces = inputdlg('Enter space-separated wavenumbers to plot:',...
                         'Input desired wavenumbers', [1 50]);
                    if isempty(app.PP_Data.SelTraces)
                        return
                    end
                    app.PP_Data.SelTraces = str2num(app.PP_Data.SelTraces{:})';
                    app.PP_Data.SelTraces = sort(app.PP_Data.SelTraces,1);
            end
            % Check if RAW or CORR and give DATA
            switch app.PP_Data.rawcorr
                case 'RAW'
                    Zdata = app.PP_Data.rawsignal{DET};
                case 'CORRECTED'
                    Zdata = app.PP_Data.corrdata{DET};
            end
            
            % Call the plotting subroutine
            % k=index of the plotted WL, ydata=data, yres=residuals of fit (if present)
            [~,selCol,ydata,yfit,yres] = plot_KineticTraces(app.PP_Data,DET,Zdata,2,6,app.PP_NormaliseCheckBox.Value,app.PP_IncludeResiduals.Value);
            
            Ndelays = size(ydata,1);

            % Decide whether to save the plotted traces or not
            if app.PP_SavetracesSwitch.Value == 1              
                selWN = transpose(app.PP_Data.cmprobe{DET}(selCol));
                if ~isempty(yres)
                    fn_res    = [app.PP_Data.rootdir filesep app.PP_Data.datafilename '_DFRkin-traces_DET' num2str(DET) '.dat'];
                    tvector = [0;app.PP_Data.delays];
                    writeZ  = zeros(Ndelays,3.*length(selCol));
                    writeWN = zeros(1,3.*length(selCol));
                    for i=1:length(selCol)
                        idxCol              = (3*(i-1)+1):(3*i);
                        writeZ(:,idxCol)    = [ydata(:,i) yfit(:,i) yres(:,i)];
                        writeWN(:,idxCol)   = [selWN(i) -selWN(i) 0];
                    end
                    writematrix([tvector [writeWN; writeZ]],fn_res,'Delimiter',',')
                    helpdlg(["Saved [ DATA | FIT | RES ] traces to: ";fn_res],'Traces have been saved!')
                else
                    filename    = [app.PP_Data.rootdir filesep app.PP_Data.datafilename '_Kin-traces_DET' num2str(DET) '.dat'];
                    writematrix([[0;app.PP_Data.delays] [selWN;ydata]],filename,'Delimiter',',')
                    helpdlg(["Saved traces to: ";filename],'Traces have been saved!')
                end
                
            end
        end

        % Button pushed function: CopyTaus_button
        function CopyTaus_buttonPushed(app, event)
            % Copies current stored parameters as initial guesses
            % First, I need to get the KineticModel structure
            pFit        = app.KineticModel.FitStruct.pFit;
            FixTAU      = app.KineticModel.FitStruct.FixTAU;
            FixIRF      = app.KineticModel.FitStruct.FixIRF;
            IsFixTau    = app.KineticModel.FitStruct.IsFixTau;
            IsFixIRF    = app.KineticModel.FitStruct.IsFixIRF;
            GauIRF      = app.KineticModel.FitStruct.GauIRF;
            
            % Then, I need to sort ALL the taus:
            beginTau            = (1+length(IsFixIRF)-sum(IsFixIRF));
            FitTaus             = pFit(beginTau:end);
            AllTaus(IsFixTau)   = FixTAU;
            AllTaus(~IsFixTau)  = FitTaus;
            
            num2clip(AllTaus(:));
        end

        % Button pushed function: pFit_to_p0_Button
        function pFit_to_p0_ButtonPushed(app, event)
            try
                % Copies current stored parameters as initial guesses
                % First, I need to get the KineticModel structure
                pFit        = app.KineticModel.FitStruct.pFit;
                FixTAU      = app.KineticModel.FitStruct.FixTAU;
                FixIRF      = app.KineticModel.FitStruct.FixIRF;
                IsFixTau    = app.KineticModel.FitStruct.IsFixTau;
                IsFixIRF    = app.KineticModel.FitStruct.IsFixIRF;
                GauIRF      = app.KineticModel.FitStruct.GauIRF;
                
                % Then, I need to sort ALL the taus:
                beginTau            = (1+length(IsFixIRF)-sum(IsFixIRF));
                FitTaus             = pFit(beginTau:end);
                AllTaus(IsFixTau)   = FixTAU;
                AllTaus(~IsFixTau)  = FitTaus;
                
                % Read parameters off the parameter vector (p)
                if IsFixIRF(1) == 1
                    t0  = FixIRF(1);
                else
                    t0  = pFit(1);
                end
            
                if GauIRF == 1
                    if IsFixIRF(2) == 1
                        FWHM  = FixIRF(end);
                    else
                        FWHM  = pFit(2);
                    end
                else
                    FWHM = [];
                end
                
                IRFpar = [t0 FWHM];
                % Convert the arrays to tables and write back to the GUI
                app.RateFitTable.Data(:,1)  = num2cell(AllTaus(:));
                app.IRFtable.Data(:,1)      = num2cell(IRFpar(:));
            catch
            end
        end

        % Button pushed function: PP_ContourplotButton
        function PP_ContourplotButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrokineticFittingUIFigure = uifigure('Visible', 'off');
            app.SpectrokineticFittingUIFigure.Name = 'Spectrokinetic Fitting';
            app.MainAxes = uiaxes(app.SpectrokineticFittingUIFigure);
            title(app.MainAxes, 'Title')
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            zlabel(app.MainAxes, 'Z')
            app.MainAxes.Visible = 'off';
            app.KineticsAxes = uiaxes(app.SpectrokineticFittingUIFigure);
            xlabel(app.KineticsAxes, 'X')
            ylabel(app.KineticsAxes, 'Y')
            zlabel(app.KineticsAxes, 'Z')
            app.KineticsAxes.Visible = 'off';
            app.SpectralAxes = uiaxes(app.SpectrokineticFittingUIFigure);
            xlabel(app.SpectralAxes, 'X')
            ylabel(app.SpectralAxes, 'Y')
            zlabel(app.SpectralAxes, 'Z')
            app.SpectralAxes.Visible = 'off';
            app.IRFandtimezeroPanel = uipanel(app.SpectrokineticFittingUIFigure);
            app.IRFandtimezeroPanel.Title = 'IRF and time-zero';
            app.IRFandtimezeroPanel.Visible = 'off';
            app.LoadPDATButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.LoadPDATButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPDATButtonPushed, true);
            app.LoadPDATButton.Text = 'Load PDAT';
            app.PlotWhatButtonGroup = uibuttongroup(app.SpectrokineticFittingUIFigure);
            app.PlotWhatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PlotWhatButtonGroup.Enable = 'off';
            app.PlotWhatButtonGroup.Title = 'What to plot?';
            app.PlotWhatButtonGroup.Visible = 'off';
            app.ShowDataButton = uitogglebutton(app.PlotWhatButtonGroup);
            app.ShowDataButton.Text = 'Data';
            app.ShowDataButton.Value = true;
            app.ShowFitButton = uitogglebutton(app.PlotWhatButtonGroup);
            app.ShowFitButton.Enable = 'off';
            app.ShowFitButton.Text = 'Fit';
            app.ShowResidualsButton = uitogglebutton(app.PlotWhatButtonGroup);
            app.ShowResidualsButton.Enable = 'off';
            app.ShowResidualsButton.Text = 'Residuals';
            app.ClearDataButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Enable = 'off';
            app.ClearDataButton.Text = 'Clear Data';
            app.DataLoadedLampLabel = uilabel(app.SpectrokineticFittingUIFigure);
            app.DataLoadedLampLabel.Text = 'Data Loaded';
            app.DataLoadedLamp = uilamp(app.SpectrokineticFittingUIFigure);
            app.FITDATAButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.FITDATAButton.ButtonPushedFcn = createCallbackFcn(app, @DoFitOrPreview_Function, true);
            app.FITDATAButton.Visible = 'off';
            app.FITDATAButton.Text = 'FIT DATA';
            app.SaveButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.ModelSelectionButtonGroup = uibuttongroup(app.SpectrokineticFittingUIFigure);
            app.ModelSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelSelectionButtonGroupSelectionChanged, true);
            app.ModelSelectionButtonGroup.Title = 'Model Selection';
            app.ModelSelectionButtonGroup.Visible = 'off';
            app.ParallelButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.ParallelButton.Value = true;
            app.SequentialButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.SequentialButton.Text = 'Sequential';
            app.TargetButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.TargetButton.Text = 'Target';
            app.ODEdefinedButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.ODEdefinedButton.Enable = 'off';
            app.ODEdefinedButton.Text = 'ODE-defined';
            app.RateFitTable = uitable(app.SpectrokineticFittingUIFigure);
            app.RateFitTable.ColumnName = {'Tau'; 'UB'; 'LB'; 'Fix?'};
            app.RateFitTable.RowName = {'1;2;3'};
            app.RateFitTable.SelectionType = 'row';
            app.RateFitTable.ColumnEditable = true;
            app.RateFitTable.Multiselect = 'off';
            app.RateFitTable.Visible = 'off';
            app.PreviewButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @DoFitOrPreview_Function, true);
            app.PreviewButton.Visible = 'off';
            app.PreviewButton.Text = 'Preview';
            app.ResetFitButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.ResetFitButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFitButtonPushed, true);
            app.ResetFitButton.Visible = 'off';
            app.ResetFitButton.Text = 'Reset';
            app.AddComponentButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.AddComponentButton.ButtonPushedFcn = createCallbackFcn(app, @AddComponentButtonPushed, true);
            app.AddComponentButton.Visible = 'off';
            app.AddComponentButton.Text = 'Add Component';
            app.RemoveComponentButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.RemoveComponentButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveComponentButtonPushed, true);
            app.RemoveComponentButton.Visible = 'off';
            app.RemoveComponentButton.Text = 'Remove Component';
            app.PP_PlotcontrolsPanel = uipanel(app.SpectrokineticFittingUIFigure);
            app.PP_PlotcontrolsPanel.Title = 'Plot controls';
            app.PP_PlotcontrolsPanel.Visible = 'off';
            app.PP_ShowContours_tick = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_ShowContours_tick.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_ShowContours_tick.Text = 'Show contour lines, every          levels. Format:';
            app.PP_NskipContours = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_NskipContours.Limits = [1 Inf];
            app.PP_NskipContours.RoundFractionalValues = 'on';
            app.PP_NskipContours.ValueDisplayFormat = '%.0f';
            app.PP_NskipContours.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_NskipContours.Value = 2;
            app.PP_ContourFormat = uieditfield(app.PP_PlotcontrolsPanel, 'text');
            app.PP_ContourFormat.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_ContourFormat.Value = '-';
            app.PP_PlotScaleNumber = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_PlotScaleNumber.LowerLimitInclusive = 'off';
            app.PP_PlotScaleNumber.Limits = [1e-06 100];
            app.PP_PlotScaleNumber.ValueDisplayFormat = '%.3f';
            app.PP_PlotScaleNumber.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_PlotScaleNumber.Value = 100;
            app.PP_SymmetricColourScale = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_SymmetricColourScale.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_SymmetricColourScale.Text = 'Symmetric colour scale';
            app.PP_SymmetricColourScale.Value = true;
            app.PP_FilledContours_tick = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_FilledContours_tick.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_FilledContours_tick.Text = 'Filled contours';
            app.PP_FilledContours_tick.Value = true;
            app.PP_LogTime_tick = uicheckbox(app.PP_PlotcontrolsPanel);
            app.PP_LogTime_tick.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_LogTime_tick.Text = 'Logarithmic time axis';
            app.PP_LogTime_tick.Value = true;
            app.ZScaleLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.ZScaleLabel.Text = 'Z Scale (%)';
            app.PP_PlotScaleSlider = uislider(app.PP_PlotcontrolsPanel);
            app.PP_PlotScaleSlider.Limits = [1e-06 100];
            app.PP_PlotScaleSlider.MajorTicks = [1e-06 20.000001 40.000001 60.000001 80.000001 100];
            app.PP_PlotScaleSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.PP_PlotScaleSlider.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_PlotScaleSlider.ValueChangingFcn = createCallbackFcn(app, @PP_PlotScaleSliderValueChanging, true);
            app.PP_PlotScaleSlider.MinorTicks = [1e-06 4.000001 8.000001 12.000001 16.000001 20.000001 24.000001 28.000001 32.000001 36.000001 40.000001 44.000001 48.000001 52.000001 56.000001 60.000001 64.000001 68.000001 72.000001 76.000001 80.000001 84.000001 88.000001 92.000001 96.000001 100];
            app.PP_PlotScaleSlider.Tooltip = {'Set the Z scale of the contour plot'; ' relative to the maximum transient absorption signal. For anisotropy plots'; ' the scale is fixed.'};
            app.PP_PlotScaleSlider.Value = 100;
            app.MinWLEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MinWLEditFieldLabel.Text = 'Min WL';
            app.PP_MinWL = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MinWL.ValueDisplayFormat = '%.2f';
            app.PP_MinWL.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.MaxWLEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MaxWLEditFieldLabel.Text = 'Max WL';
            app.PP_MaxWL = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MaxWL.ValueDisplayFormat = '%.2f';
            app.PP_MaxWL.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.MaxtimeLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MaxtimeLabel.Text = 'Max time';
            app.PP_MaxTime = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MaxTime.ValueDisplayFormat = '%.4f';
            app.PP_MaxTime.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.MintimeLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MintimeLabel.Text = 'Min time';
            app.PP_MinTime = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_MinTime.ValueDisplayFormat = '%.4f';
            app.PP_MinTime.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.NofwhitelevelsLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.NofwhitelevelsLabel.Text = 'Nº of white levels';
            app.PP_NWhites = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_NWhites.Limits = [0 Inf];
            app.PP_NWhites.ValueDisplayFormat = '%.0f';
            app.PP_NWhites.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_NWhites.Value = 2;
            app.AbsrangeEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.AbsrangeEditFieldLabel.Text = '∆Abs range';
            app.PP_DeltaAbsPlotRange = uieditfield(app.PP_PlotcontrolsPanel, 'text');
            app.PP_DeltaAbsPlotRange.Value = '0, 0';
            app.MaxAbsmODLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.MaxAbsmODLabel.Text = 'Max ∆Abs (mOD)';
            app.PP_MaxDeltaAbs = uieditfield(app.PP_PlotcontrolsPanel, 'text');
            app.PP_MaxDeltaAbs.Editable = 'off';
            app.PP_MaxDeltaAbs.Value = '0, 0';
            app.PP_RestoredefaultlimitsButton = uibutton(app.PP_PlotcontrolsPanel, 'push');
            app.PP_RestoredefaultlimitsButton.ButtonPushedFcn = createCallbackFcn(app, @PP_RestoredefaultlimitsButtonPushed, true);
            app.PP_RestoredefaultlimitsButton.Text = 'Restore Limits';
            app.ColourschemeDropDownLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.ColourschemeDropDownLabel.Text = 'Colour scheme';
            app.PP_ColourScheme = uidropdown(app.PP_PlotcontrolsPanel);
            app.PP_ColourScheme.Items = {'RdOr/Wh/Bl', 'DkRd/Wh/DkBl', 'Rd/Wh/Bl', 'Spectral', 'Jet', 'Turbo'};
            app.PP_ColourScheme.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_ColourScheme.Tooltip = {'Select the colour scheme for the plots'};
            app.PP_ColourScheme.Value = 'DkRd/Wh/DkBl';
            app.NofcontoursEditFieldLabel = uilabel(app.PP_PlotcontrolsPanel);
            app.NofcontoursEditFieldLabel.Text = 'Nº of contours';
            app.PP_NContours = uieditfield(app.PP_PlotcontrolsPanel, 'numeric');
            app.PP_NContours.Limits = [1 Inf];
            app.PP_NContours.RoundFractionalValues = 'on';
            app.PP_NContours.ValueDisplayFormat = '%.0f';
            app.PP_NContours.ValueChangedFcn = createCallbackFcn(app, @PP_UpdatedPlotDetails, true);
            app.PP_NContours.Value = 40;
            app.FitOptionsButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.FitOptionsButton.Enable = 'off';
            app.FitOptionsButton.Visible = 'off';
            app.FitOptionsButton.Text = 'Fit Options...';
            app.DefinemodelButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.DefinemodelButton.ButtonPushedFcn = createCallbackFcn(app, @DefinemodelButtonPushed, true);
            app.DefinemodelButton.Visible = 'off';
            app.DefinemodelButton.Text = 'Define model...';
            app.ExtraPlotsAfterFit_CheckBox = uicheckbox(app.SpectrokineticFittingUIFigure);
            app.ExtraPlotsAfterFit_CheckBox.Visible = 'off';
            app.ExtraPlotsAfterFit_CheckBox.Text = 'Plot Data+Fit+Residuals (contours)';
            app.AddTracestoLivePlotsButton = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.AddTracestoLivePlotsButton.Visible = 'off';
            app.AddTracestoLivePlotsButton.Text = {'Add Traces'; 'to Live Plots'};
            app.PP_PlotsandcutsPanel = uipanel(app.SpectrokineticFittingUIFigure);
            app.PP_PlotsandcutsPanel.Title = 'Additional Plots and cuts';
            app.PP_PlotsandcutsPanel.Visible = 'off';
            app.PlotsAndCuts = uigridlayout(app.PP_PlotsandcutsPanel);
            app.PP_ContourplotButton = uibutton(app.PlotsAndCuts, 'push');
            app.PP_ContourplotButton.ButtonPushedFcn = createCallbackFcn(app, @PP_ContourplotButtonPushed, true);
            app.PP_ContourplotButton.Enable = 'off';
            app.PP_ContourplotButton.Text = 'Contour Plots';
            app.PP_SurfaceplotButton = uibutton(app.PlotsAndCuts, 'push');
            app.PP_SurfaceplotButton.Enable = 'off';
            app.PP_SurfaceplotButton.Text = '3D Surface plot';
            app.PP_PlotKineticsButton = uibutton(app.PlotsAndCuts, 'push');
            app.PP_PlotKineticsButton.ButtonPushedFcn = createCallbackFcn(app, @PP_PlotKineticsButtonPushed, true);
            app.PP_PlotKineticsButton.Text = 'Plot Kinetics';
            app.PP_PlotTrSpectraButton = uibutton(app.PlotsAndCuts, 'push');
            app.PP_PlotTrSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PP_PlotTrSpectraButtonPushed, true);
            app.PP_PlotTrSpectraButton.Text = 'Plot Tr. Spectra';
            app.PP_NormaliseCheckBox = uicheckbox(app.PlotsAndCuts);
            app.PP_NormaliseCheckBox.Tooltip = {'Tick to normalise the transient absorption spectra or kinetic traces to the maximum value.'};
            app.PP_NormaliseCheckBox.Text = 'Normalise';
            app.PP_InteractivemodeSwitch = uicheckbox(app.PlotsAndCuts);
            app.PP_InteractivemodeSwitch.Tooltip = {'Tick to normalise the transient absorption spectra or kinetic traces to the maximum value.'};
            app.PP_InteractivemodeSwitch.Text = 'Interactive';
            app.PP_InteractivemodeSwitch.Value = true;
            app.PP_IncludeResiduals = uicheckbox(app.PlotsAndCuts);
            app.PP_IncludeResiduals.Enable = 'off';
            app.PP_IncludeResiduals.Text = 'Incl. Residuals';
            app.PP_IncludeResiduals.Value = true;
            app.PP_SavetracesSwitch = uicheckbox(app.PlotsAndCuts);
            app.PP_SavetracesSwitch.Tooltip = {'Select to save KINETIC traces after plotting. Will automatically include residuals if a fit was done.'};
            app.PP_SavetracesSwitch.Text = 'Save Traces';
            app.ExplorerModeButton = uibutton(app.SpectrokineticFittingUIFigure, 'state');
            app.ExplorerModeButton.Visible = 'off';
            app.ExplorerModeButton.Text = 'Explorer Mode';
            app.VersionLabel = uilabel(app.SpectrokineticFittingUIFigure);
            app.VersionLabel.Text = 'VersionLabel';
            app.Model_pFIDCheckBox = uicheckbox(app.SpectrokineticFittingUIFigure);
            app.Model_pFIDCheckBox.Enable = 'off';
            app.Model_pFIDCheckBox.Visible = 'off';
            app.Model_pFIDCheckBox.Text = 'Model pFID';
            app.IRFtable = uitable(app.SpectrokineticFittingUIFigure);
            app.IRFtable.ColumnName = {'Val.'; 'UB'; 'LB'; 'Fix?'};
            app.IRFtable.RowName = {'t0;FWHM'};
            app.IRFtable.SelectionType = 'row';
            app.IRFtable.ColumnEditable = true;
            app.IRFtable.Multiselect = 'off';
            app.IRFtable.Visible = 'off';
            app.RestrictFitCheckBox = uicheckbox(app.SpectrokineticFittingUIFigure);
            app.RestrictFitCheckBox.Visible = 'off';
            app.RestrictFitCheckBox.Text = 'Restrict fit to current display limits';
            app.RestrictFitCheckBox.Value = true;
            app.DefaultConcCheckBox = uicheckbox(app.SpectrokineticFittingUIFigure);
            app.DefaultConcCheckBox.Visible = 'off';
            app.DefaultConcCheckBox.Text = 'Default Initial Conc.';
            app.DefaultConcCheckBox.Value = true;
            app.FitMethod = uidropdown(app.SpectrokineticFittingUIFigure);
            app.FitMethod.Items = {'Levenberg-Marquardt', 'Trust-Region-Reflective', 'Constrained Fit (fmincon)', 'Particle Swarm', 'Genetic Algorithm'};
            app.FitMethod.Visible = 'off';
            app.FitMethod.Value = 'Levenberg-Marquardt';
            app.CopyTaus_button = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.CopyTaus_button.ButtonPushedFcn = createCallbackFcn(app, @CopyTaus_buttonPushed, true);
            app.CopyTaus_button.Enable = 'off';
            app.CopyTaus_button.Visible = 'off';
            app.CopyTaus_button.Text = 'Copy';
            app.Chi2Value_label = uilabel(app.SpectrokineticFittingUIFigure);
            app.Chi2Value_label.Visible = 'off';
            app.Chi2Value_label.Text = 'χ² =';
            app.Chi2Value = uieditfield(app.SpectrokineticFittingUIFigure, 'numeric');
            app.Chi2Value.Editable = 'off';
            app.Chi2Value.Visible = 'off';
            app.pFit_to_p0_Button = uibutton(app.SpectrokineticFittingUIFigure, 'push');
            app.pFit_to_p0_Button.ButtonPushedFcn = createCallbackFcn(app, @pFit_to_p0_ButtonPushed, true);
            app.pFit_to_p0_Button.Enable = 'off';
            app.pFit_to_p0_Button.Visible = 'off';
            app.pFit_to_p0_Button.Text = 'pFit→p0 ';
            app.GaussianIRFCheckBox = uicheckbox(app.SpectrokineticFittingUIFigure);
            app.GaussianIRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianIRFCheckBoxValueChanged, true);
            app.GaussianIRFCheckBox.Visible = 'off';
            app.GaussianIRFCheckBox.Text = 'Gaussian IRF?';
            app.GaussianIRFCheckBox.Value = true;
            % Show the figure after all components are created
            app.SpectrokineticFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KineticsFitWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrokineticFittingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrokineticFittingUIFigure)
        end
    end
end